classdef Interfaz_Control_Actuadores < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Modo2sensoresLamp               matlab.ui.control.Lamp
        Modo2sensoresLampLabel          matlab.ui.control.Label
        Modo1SensorLamp                 matlab.ui.control.Lamp
        Modo1SensorLampLabel            matlab.ui.control.Label
        ModoBinarioLamp                 matlab.ui.control.Lamp
        ModoBinarioLampLabel            matlab.ui.control.Label
        ClasificacinGauge               matlab.ui.control.LinearGauge
        ClasificacinGaugeLabel          matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        InstruccionesTab                matlab.ui.container.Tab
        Label_24                        matlab.ui.control.Label
        Label_23                        matlab.ui.control.Label
        Label_22                        matlab.ui.control.Label
        Label_21                        matlab.ui.control.Label
        Label_20                        matlab.ui.control.Label
        Label_19                        matlab.ui.control.Label
        Label_18                        matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        Label_16                        matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Modo1y2SensoresLabel            matlab.ui.control.Label
        ModoBinarioLabel                matlab.ui.control.Label
        InstruccionesparausoconBitalinosLabel  matlab.ui.control.Label
        InstruccionesparausoconMyoWareLabel  matlab.ui.control.Label
        InstruccionesparaelusodelaInterfazLabel  matlab.ui.control.Label
        EntrenamientoTab                matlab.ui.container.Tab
        CantidaddesensoresBITalinoPanel  matlab.ui.container.Panel
        SensoresCheckBox                matlab.ui.control.CheckBox
        SensorCheckBox                  matlab.ui.control.CheckBox
        SeleccindeMtodosdeclasificacinPanel  matlab.ui.container.Panel
        PorcentajedeValidacinEditField_3  matlab.ui.control.NumericEditField
        PorcentajedeValidacinEditField_3Label  matlab.ui.control.Label
        PorcentajedeValidacinEditField_2  matlab.ui.control.NumericEditField
        PorcentajedeValidacinEditField_2Label  matlab.ui.control.Label
        PorcentajedeValidacinEditField  matlab.ui.control.NumericEditField
        PorcentajedeValidacinEditFieldLabel  matlab.ui.control.Label
        SeleccionarMtododeClasificacinButton  matlab.ui.control.Button
        Lamp_4                          matlab.ui.control.Lamp
        Lamp_3                          matlab.ui.control.Lamp
        Lamp_2                          matlab.ui.control.Lamp
        SeleccionartodosCheckBox        matlab.ui.control.CheckBox
        ANNCheckBox                     matlab.ui.control.CheckBox
        KNNCheckBox                     matlab.ui.control.CheckBox
        SVMCheckBox                     matlab.ui.control.CheckBox
        SeleccinArchivodeEntrenamientoPanel  matlab.ui.container.Panel
        SeleccionarArchivomatButton     matlab.ui.control.Button
        PaneldecontrolTab               matlab.ui.container.Tab
        SeleccindecontrolPanel          matlab.ui.container.Panel
        CalibradoLamp                   matlab.ui.control.Lamp
        CalibradoLampLabel              matlab.ui.control.Label
        CalibracionButton               matlab.ui.control.Button
        Control2sensoresCheckBox        matlab.ui.control.CheckBox
        Control1sensorCheckBox          matlab.ui.control.CheckBox
        ControlBinarioCheckBox          matlab.ui.control.CheckBox
        Lamp                            matlab.ui.control.Lamp
        IniciarControlSwitch            matlab.ui.control.Switch
        IniciarControlSwitchLabel       matlab.ui.control.Label
        ConexionesPanel                 matlab.ui.container.Panel
        EmpezarStreamingSwitch_2Label_2  matlab.ui.control.Label
        EmpezarStreaming2Switch         matlab.ui.control.Switch
        ConectarBITalino2SwitchLabel    matlab.ui.control.Label
        ConectarBITalino2Switch         matlab.ui.control.Switch
        NombreBITalino2Label            matlab.ui.control.Label
        EditField_3                     matlab.ui.control.EditField
        ConectaraManoAnimatrnicaSwitchLabel  matlab.ui.control.Label
        ConectaraManoAnimatrnicaSwitch  matlab.ui.control.Switch
        PuertoManoAnimatrnicaDropDown   matlab.ui.control.DropDown
        PuertoManoAnimatrnicaDropDownLabel  matlab.ui.control.Label
        ConectaraArduinoSwitch_2Label   matlab.ui.control.Label
        ConectaraArduinoSwitch_2        matlab.ui.control.Switch
        PuertoMyowareDropDown_2         matlab.ui.control.DropDown
        PuertoMyowareDropDown_2Label    matlab.ui.control.Label
        EmpezarStreamingSwitch_2Label   matlab.ui.control.Label
        EmpezarStreamingSwitch          matlab.ui.control.Switch
        ConectarBITalinoSwitch_3Label   matlab.ui.control.Label
        ConectarBITalinoSwitch          matlab.ui.control.Switch
        NombreBITalinoLabel             matlab.ui.control.Label
        EditField_2                     matlab.ui.control.EditField
        Panel                           matlab.ui.container.Panel
        BITalino2Lamp                   matlab.ui.control.Lamp
        BITalino2LampLabel              matlab.ui.control.Label
        ManoAnimatrnicaLamp             matlab.ui.control.Lamp
        ManoAnimatrnicaLampLabel        matlab.ui.control.Label
        MyoWareLamp                     matlab.ui.control.Lamp
        MyoWareLampLabel                matlab.ui.control.Label
        BITalino1Lamp                   matlab.ui.control.Lamp
        BITalino1LampLabel              matlab.ui.control.Label
        ControldeManoAnimatrnicaUVGLabel  matlab.ui.control.Label
        figFpz_2                        matlab.ui.control.UIAxes
        figFpz                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Propiedades de comunicación serial
        PuertoSerialMano  %Puerto mano animatrónica
        PuertoSerialArduino % Puerto conexión Arduino
        puertoMA = 'COM3' %Puerto default mano
        puertoAr = 'COM17' %Puerto default Arduino

        %Propiedades de BITalino
        T=0; % Description
        n=0;
        DataEnBrutoEMG = cell(0,0)
        DataEnBrutoEMG1 = cell(0,0)
        Bit1=0;
        Bit2=0;
        F_pb = filtro_pasa_banda(1000,20,450);    %Diseñar filtro pasa banda
        F_notch = filtro_rechaza_banda(1000);     %Diseñar filtro rechaza banda
        data_n=0;
        data_n1=0;
        BanderaParaEmpezar=0;
        NombreBit=0;
        NombreBit1=0;
        flag_recibir1_2=0;
        flag_recibir2_2=0;
        flag_recibir1_1=0;
        flag_recibir2_1=0;
        flag_terminar=0;
        Pausa=0;
        ctrl2=0;
         time = 0;
            NMuestras=0;
        % Variables detección de señal
        buffer = zeros(1,3000);
        pasado = 1;
        presente = 1;
        l = 1;
        menor = 0;
        mayor = 0;
        EMG_ventana = [];
        EMGp = [];  
        Promedio = 0;
        Promedio2 = 0;

        %Variables detección bitalino 2
        buffer1 = zeros(1,3000);
        pasado1 = 1;
        presente1 = 1;
        l1 = 1;
        menor1 = 0;
        mayor1 = 0;
        EMG_ventana1 = [];
        EMGp1 = [];  
        
        %Variables separación en ventanas
        EMG_s1 = zeros(1,134);
        EMG_s2 = zeros(1,133);
        EMG_s3 = zeros(1,133);

        EMG_s1_2 = zeros(1,134);
        EMG_s2_2 = zeros(1,133);
        EMG_s3_2 = zeros(1,133);

        receivedArray = [];
        
        %Propiedades de entrenamiento
        loadedData
        BDentrenamiento = 0;
        entreSVM
        entreANN
        entreKNN
        BEntre

        %Banderas control
        banAr1 = 0;
        banAr2 = 0;
        banS1 = 0;
        banS2 = 0;
        banMano = 0;
        tipoDato = 0;

        banBit = 0;
        sig = zeros(1,7);
        sig1 = zeros(1,13);

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ControlBinarioCheckBox
        function ControlBinarioCheckBoxValueChanged(app, event)
            value = app.ControlBinarioCheckBox.Value;
            if value == 1
                app.ConectaraArduinoSwitch_2.Enable = "on";
                app.ConectaraManoAnimatrnicaSwitch.Enable = "on";
                app.Control1sensorCheckBox.Enable = "off";
                app.Control2sensoresCheckBox.Enable = "off";
                app.IniciarControlSwitch.Enable = "on";
                app.ModoBinarioLamp.Enable = "on";
                app.tipoDato = 1;
                app.banAr2 = 1;
            else
                app.ConectaraArduinoSwitch_2.Enable = "off";
                app.ConectaraManoAnimatrnicaSwitch.Enable = "off";
                app.Control1sensorCheckBox.Enable = "on";
                app.Control2sensoresCheckBox.Enable = "on";
                app.IniciarControlSwitch.Enable = "off";
                app.ModoBinarioLamp.Enable = "off";
                app.tipoDato = 0;
                app.banAr2 = 0;
            end
        end

        % Value changed function: Control1sensorCheckBox
        function Control1sensorCheckBoxValueChanged(app, event)
            value = app.Control1sensorCheckBox.Value;
            if value == 1
                app.ConectaraArduinoSwitch_2.Enable = "on";
                app.ConectaraManoAnimatrnicaSwitch.Enable = "on";
                app.ConectarBITalinoSwitch.Enable = "on";
                app.EmpezarStreamingSwitch.Enable = "on";
                app.Control2sensoresCheckBox.Enable = "off";
                app.ControlBinarioCheckBox.Enable = "off";
                app.IniciarControlSwitch.Enable = "on";
                app.Modo1SensorLamp.Enable = "on";
                app.tipoDato = 2;
                
            else
                app.ConectaraArduinoSwitch_2.Enable = "off";
                app.ConectaraManoAnimatrnicaSwitch.Enable = "off";
                app.ConectarBITalinoSwitch.Enable = "off";
                app.EmpezarStreamingSwitch.Enable = "off";
                app.Control2sensoresCheckBox.Enable = "on";
                app.ControlBinarioCheckBox.Enable = "on";
                app.IniciarControlSwitch.Enable = "off";
                app.Modo1SensorLamp.Enable = "off";
                app.tipoDato = 0;
                app.banS1 = 0;
            end
        end

        % Value changed function: Control2sensoresCheckBox
        function Control2sensoresCheckBoxValueChanged(app, event)
            value = app.Control2sensoresCheckBox.Value;
            if value == 1
                app.ConectaraArduinoSwitch_2.Enable = "on";
                app.ConectaraManoAnimatrnicaSwitch.Enable = "on";
                app.ConectarBITalinoSwitch.Enable = "on";
                app.EmpezarStreamingSwitch.Enable = "on";
                app.ConectarBITalino2Switch.Enable = "on";
                app.EmpezarStreaming2Switch.Enable = "on";
                app.Control1sensorCheckBox.Enable = "off";
                app.ControlBinarioCheckBox.Enable = "off";
                app.IniciarControlSwitch.Enable = "on";
                app.tipoDato = 2;
                app.Modo2sensoresLamp.Enable = "on";
            else
                app.ConectaraArduinoSwitch_2.Enable = "off";
                app.ConectaraManoAnimatrnicaSwitch.Enable = "off";
                app.ConectarBITalinoSwitch.Enable = "off";
                app.EmpezarStreamingSwitch.Enable = "off";
                app.ConectarBITalino2Switch.Enable = "off";
                app.EmpezarStreaming2Switch.Enable = "off";
                app.Control1sensorCheckBox.Enable = "on";
                app.ControlBinarioCheckBox.Enable = "on";
                app.IniciarControlSwitch.Enable = "off";
                app.tipoDato = 0;
                app.banS2 = 0;
                app.Modo2sensoresLamp.Enable = "off";
            end
        end

        % Value changed function: ConectaraManoAnimatrnicaSwitch
        function ConectaraManoAnimatrnicaSwitchValueChanged(app, event)
            value = app.ConectaraManoAnimatrnicaSwitch.Value;
            if strcmp( value,'On')
                if ~exist('PuertoSerialMano', 'var') || ~isvalid(app.PuertoSerialMano)
                        app.PuertoSerialMano = serialport(app.puertoMA,9600);
                end
                app.ManoAnimatrnicaLamp.Enable = "on";
                app.banMano = 1;
            else
               clear app.PuertoSerialMano
               app.ManoAnimatrnicaLamp.Enable = "off";
               app. banMano = 0;
            end
        end

        % Value changed function: PuertoMyowareDropDown_2
        function PuertoMyowareDropDown_2ValueChanged(app, event)
            value = app.PuertoMyowareDropDown_2.Value;
            app.puertoAr = value;
        end

        % Value changed function: PuertoManoAnimatrnicaDropDown
        function PuertoManoAnimatrnicaDropDownValueChanged(app, event)
            value = app.PuertoManoAnimatrnicaDropDown.Value;
            app.puertoMA = value;
        end

        % Value changed function: ConectaraArduinoSwitch_2
        function ConectaraArduinoSwitch_2ValueChanged(app, event)
            value = app.ConectaraArduinoSwitch_2.Value;
            if strcmp( value,'On')
                % if ~exist('PuertoSerialArduino', 'var') || ~isvalid(PuertoSerialArduino)
                %         app.PuertoSerialArduino = serialport(app.puertoAr,115200);
                % end
                app.PuertoSerialArduino = serialport(app.puertoAr,115200);
                configureTerminator(app.PuertoSerialArduino, "LF");
                app.MyoWareLamp.Enable = "on";
                app.banAr1 = 1;
            else
               % if exist('PuertoSerialArduino', 'var') || ~isvalid(PuertoSerialArduino)
               %          clear(app.PuertoSerialArduino)
               % end 
               clear app.PuertoSerialArduino 
               app.banAr1 = 0;
               app.MyoWareLamp.Enable = "off";
            end
        end

        % Value changed function: EmpezarStreamingSwitch
        function EmpezarStreamingSwitchValueChanged(app, event)
            value = app.EmpezarStreamingSwitch.Value;
            if strcmp( value,'On')
                app.flag_recibir2_1 =1;
                startBackground(app.Bit1)
                app.banS1 = 1;
            else
                app.flag_recibir2_1 =0;
                stopBackground(app.Bit1)
                app.banS1 = 0;
            end
        end

        % Value changed function: ConectarBITalinoSwitch
        function ConectarBITalinoSwitchValueChanged(app, event)
            value = app.ConectarBITalinoSwitch.Value;
            if strcmp( value,'On')
                app.Bit1 = Bitalino('btspp://201806130189');
                %app.NombreBit1 = app.EditField_2.Value;
                %app.Bit1 = Bitalino(char(app.NombreBit1)); %Creamos la conexion con el dispositivo bitalino y esa conexion la volvemos un objeto.
                app.flag_recibir1_1=1;
                app.BITalino1Lamp.Enable = "on";
            else
                app.flag_recibir1_1=0;
                app.BITalino1Lamp.Enable = "off";
                clear app.NombreBit1 app.Bit1
            end
        end

        % Value changed function: ConectarBITalino2Switch
        function ConectarBITalino2SwitchValueChanged(app, event)
            value = app.ConectarBITalino2Switch.Value;
            if strcmp( value,'On')
                %app.NombreBit2 = app.EditField_3.Value;
                %app.Bit2 = Bitalino(char(app.NombreBit2)); %Creamos la conexion con el dispositivo bitalino y esa conexion la volvemos un objeto.
                app.Bit2 = Bitalino('btspp://201806132136');
                app.flag_recibir1_2=1;
                app.BITalino2Lamp.Enable = "on";
            else
                app.flag_recibir1_2=0;
                app.BITalino2Lamp.Enable = "off";
                clear app.NombreBit2 app.Bit2
            end
        end

        % Value changed function: EmpezarStreaming2Switch
        function EmpezarStreaming2SwitchValueChanged(app, event)
            value = app.EmpezarStreaming2Switch.Value;
            if strcmp( value,'On')
                app.flag_recibir2_2 =1;
                startBackground(app.Bit2)
                app.banS2 = 1;
            else
                app.flag_recibir2_2 =0;
                stopBackground(app.Bit2)
                app.banS2 = 0;
            end
        end

        % Value changed function: IniciarControlSwitch
        function IniciarControlSwitchValueChanged(app, event)
            value = app.IniciarControlSwitch.Value;
            %a = true
            Antebrazo = 127;
            Muneca_DI = 127;
            Muneca_AA = 127;
            x = 0;
            %while a
                if strcmp( value,'On')
                    if app.ControlBinarioCheckBox.Value == 1
                        while app.banAr1 && app.banAr2
                            dataAr = readline(app.PuertoSerialArduino);
                            dataAr = split(dataAr,', ');
                            dataAr = str2double(dataAr);
                            if isnan(dataAr(1))
                                continue; % Ignore NaN and go to the next iteration
                            end
                            Antebrazo = 127 + dataAr(2,1);
                            b = dataAr(1,1);
                            MATRIZ_DATA = [255, Antebrazo, Muneca_DI, Muneca_AA, b, b, b, b, b, b];
                            write(app.PuertoSerialMano,MATRIZ_DATA,"uint8");
                        end
                    end

                    if app.Control1sensorCheckBox.Value == 1
                        write(app.PuertoSerialArduino,app.tipoDato,"uint8");
                            while app.banAr1 && app.banS1 && app.banMano
                                dataAr = readline(app.PuertoSerialArduino);
                                dataAr = split(dataAr,', ');
                                dataAr = str2double(dataAr);
                                if isnan(dataAr(1))
                                    continue; % Ignore NaN and go to the next iteration
                                end
                                Antebrazo = 127 + dataAr(2,1);
                                data = read(app.Bit1);% tomamos los ultimos valores en el buffer.\
                                pause(0.5);
                                
                                EMG_raw = data(:,6); %se toma solo la columna de la senal EMG
                                EMG = EMG_raw*(3.3/1024);%se convierte la senal a la escala de 3.3 V
                                data_f = filter(app.F_pb, EMG');                          %Aplicar filtro pasa bandas
                                app.data_n = filter(app.F_notch, data_f);                       %Aplicar filtro notch

                                if size(app.data_n,2) >= 500 && size(app.data_n,2) <= 600
                                    %EMG_ventana = [EMG_ventana;l];
                                    app.EMGp = app.data_n(1, 60:end);
                                    app.presente = size(app.EMGp,2);
                                    app.l = app.presente + app.pasado;
                                    
                                    if  app.l > length(app.buffer)
                                        app.l = 1;
                                        app.buffer(1, 1:size(app.EMGp,2)) = app.EMGp; 
                                        MAV = jMeanAbsoluteDeviation(app.EMGp);
                            
                                    else
                                        app.buffer(1, app.pasado:app.l-1) = app.EMGp; 
                                        MAV = jMeanAbsoluteDeviation(app.EMGp);
                                        if MAV > app.Promedio
                                            if app.pasado - 300 < 0
                                                app.menor = app.pasado - 300;
                                                app.menor = 3000-app.menor;
                                                app.EMG_ventana = [app.buffer(1, app.menor:3000), app.buffer(1, 1:size(app.EMGp,2) + 300)];
                                                app.EMG_ventana = detecta_EMG(app.EMG_ventana,1000,500,100,1.5,0);

                                                y = app.EMG_ventana';
                                                Epocas = size(y,2) ; %se toma el tamano del buffer obtenido
                                                L = Epocas; %longitud del vector epocas
                                                            
                                                t=linspace(0, Epocas/1000, L);
                                                h1 = plot(app.figFpz, t, y, 'Color','r'); %se plotea esa muestra

                                                app.EMG_s1 = y(1,1:167);
                                                app.EMG_s2 = y(1,168:334);
                                                app.EMG_s3 = y(1,335:500);
                                                
                        
                                                ZC1_1 = jZeroCrossing(app.EMG_s1,0.1);
                                                ZC1_2 = jZeroCrossing(app.EMG_s2,0.1);
                                                ZC1_3 = jZeroCrossing(app.EMG_s3,0.1);
                                                MAV1_1 = jMeanAbsoluteDeviation(app.EMG_s1);
                                                MAV1_2 = jMeanAbsoluteDeviation(app.EMG_s2);
                                                MAV1_3 = jMeanAbsoluteDeviation(app.EMG_s3);
                                                
                                                app.sig = [ZC1_1,ZC1_2,ZC1_3, MAV1_1, MAV1_2, MAV1_3];
                        
                                                [x, y] = app.BEntre.predictFcn(app.sig);
                                                
                                            elseif app.l + 300 > 3000
                                                app.mayor = app.l + 300;
                                                app.mayor = app.mayor-3000;
                                                app.EMG_ventana = [app.buffer(1, (size(app.EMGp,2) + 300):3000),app.buffer(1, 1:app.mayor)];
                                                app.EMG_ventana = detecta_EMG(app.EMG_ventana,1000,500,100,1.5,0);
                                                y = app.EMG_ventana';
                                                Epocas = size(y,2) ; %se toma el tamano del buffer obtenido
                                                L = Epocas; %longitud del vector epocas
                                                            
                                                t=linspace(0, Epocas/1000, L);
                                                h1 = plot(app.figFpz, t, y, 'Color','r'); %se plotea esa muestra

                                                app.EMG_s1 = y(1,1:167);
                                                app.EMG_s2 = y(1,168:334);
                                                app.EMG_s3 = y(1,335:500);
                        
                                                ZC1_1 = jZeroCrossing(app.EMG_s1,0.1);
                                                ZC1_2 = jZeroCrossing(app.EMG_s2,0.1);
                                                ZC1_3 = jZeroCrossing(app.EMG_s3,0.1);
                                                MAV1_1 = jMeanAbsoluteDeviation(app.EMG_s1);
                                                MAV1_2 = jMeanAbsoluteDeviation(app.EMG_s2);
                                                MAV1_3 = jMeanAbsoluteDeviation(app.EMG_s3);
                                                
                                                app.sig = [ZC1_1,ZC1_2,ZC1_3, MAV1_1, MAV1_2, MAV1_3];
                        
                                                [x, y] = app.BEntre.predictFcn(app.sig);
                                               
                                            else
                                                app.EMG_ventana =  app.buffer(1, app.pasado - 300: app.l + 300);
                                                app.EMG_ventana = detecta_EMG(app.EMG_ventana,1000,500,100,1.5,0);

                                                y = app.EMG_ventana';
                                                Epocas = size(y,2) ; %se toma el tamano del buffer obtenido
                                                L = Epocas; %longitud del vector epocas
                                                            
                                                t=linspace(0, Epocas/1000, L);
                                                h1 = plot(app.figFpz, t, y, 'Color','r'); %se plotea esa muestra


                                                app.EMG_s1 = y(1,1:167);
                                                app.EMG_s2 = y(1,168:334);
                                                app.EMG_s3 = y(1,335:500);
                                                
                                                ZC1_1 = jZeroCrossing(app.EMG_s1,0.1);
                                                ZC1_2 = jZeroCrossing(app.EMG_s2,0.1);
                                                ZC1_3 = jZeroCrossing(app.EMG_s3,0.1);
                                                MAV1_1 = jMeanAbsoluteDeviation(app.EMG_s1);
                                                MAV1_2 = jMeanAbsoluteDeviation(app.EMG_s2);
                                                MAV1_3 = jMeanAbsoluteDeviation(app.EMG_s3);
                                                
                                                app.sig = [ZC1_1,ZC1_2,ZC1_3, MAV1_1, MAV1_2, MAV1_3];
                                                
                        
                                                [x, y] = app.BEntre.predictFcn(app.sig);


                                            end
                                        end
                                        
                                    end
                                end
                                app.pasado = app.l;


                                    app.ClasificacinGauge.Value = x;
                                    valor = x;

                                    switch valor
                                        case 0
                                            Pulgar = 170;
                                            Metacarpo = 160;
                                            Indice = 190;
                                            Medio = 190;
                                            Anular = 190;
                                            Menique = 190;
                            
                                        case 1
                                            Pulgar = 170;
                                            Metacarpo = 160;
                                            Indice = 127;
                                            Medio = 190;
                                            Anular = 190;
                                            Menique = 190;
                                        case 2
                                            Pulgar = 170;
                                            Metacarpo = 160;
                                            Indice = 127;
                                            Medio = 127;
                                            Anular = 190;
                                            Menique = 190;
                                        case 3
                                            Pulgar = 170;
                                            Metacarpo = 160;
                                            Indice = 190;
                                            Medio = 127;
                                            Anular = 127;
                                            Menique = 127;
                                        case 4
                                            Pulgar = 170;
                                            Metacarpo = 160;
                                            Indice = 127;
                                            Medio = 127;
                                            Anular = 127;
                                            Menique = 127;
                                        otherwise
                                            Pulgar = 127;
                                            Metacarpo = 127;
                                            Indice = 127;
                                            Medio = 127;
                                            Anular = 127;
                                            Menique = 127;
                            
                                    end
                                    MATRIZ_DATA = [255, Antebrazo, Muneca_DI, Muneca_AA, Pulgar, Metacarpo, Indice, Medio, Anular, Menique];
                                    write(app.PuertoSerialMano,MATRIZ_DATA,"uint8");
                                end
                            %end
                    end
                    if app.Control2sensoresCheckBox.Value == 1
                        write(app.PuertoSerialArduino,app.tipoDato,"uint8");
                            while app.banAr1 && app.banS2 && app.banS1 && app.banMano
                                dataAr = readline(app.PuertoSerialArduino);
                                dataAr = split(dataAr,', ');
                                dataAr = str2double(dataAr);
                                if isnan(dataAr(1))
                                   continue; % Ignore NaN and go to the next iteration
                                end
                                Antebrazo = 127 + dataAr(2,1);
                                
                                data = read(app.Bit1);% tomamos los ultimos valores en el buffer.\
                                data1 = read(app.Bit2);% tomamos los ultimos valores en el buffer.\
                                pause(0.5);
                                EMG_raw = data(:,6); %se toma solo la columna de la senal EMG
                                EMG = EMG_raw*(3.3/1024);%se convierte la senal a la escala de 3.3 V
                                data_f = filter(app.F_pb, EMG');                          %Aplicar filtro pasa bandas
                                app.data_n = filter(app.F_notch, data_f);                       %Aplicar filtro notch
            
                                EMG_raw1 = data1(:,6); %se toma solo la columna de la senal EMG
                                EMG1 = EMG_raw1*(3.3/1024);%se convierte la senal a la escala de 3.3 V
                                data_f1 = filter(app.F_pb, EMG1');                          %Aplicar filtro pasa bandas
                                app.data_n1 = filter(app.F_notch, data_f1);   
            
                                if size(app.data_n,2) >= 500 && size(app.data_n,2) <= 600
                                    app.EMGp = app.data_n(1, 60:end);
                                    app.presente = size(app.EMGp,2);
                                    app.l = app.presente + app.pasado;
            
                                    app.EMGp1 = app.data_n1(1, 60:end);
                                    app.presente1 = size(app.EMGp1,2);
                                    app.l1 = app.presente1 + app.pasado1;
                                    
                                    if  app.l > length(app.buffer)
                                        app.l = 1;
                                        app.buffer(1, 1:size(app.EMGp,2)) = app.EMGp; 
                                        MAV = jMeanAbsoluteDeviation(app.EMGp);
            
                                        app.l1 = 1;
                                        app.buffer1(1, 1:size(app.EMGp1,2)) = app.EMGp1; 
                                        MAV1 = jMeanAbsoluteDeviation(app.EMGp1);
            
                                        MAVs = [MAV, MAV1];
                            
                                    else
                                        app.buffer(1, app.pasado:app.l-1) = app.EMGp; 
                                        app.buffer1(1, app.pasado1:app.l1-1) = app.EMGp1; 
                                        MAV = jMeanAbsoluteDeviation(app.EMGp);
                                        MAV1 = jMeanAbsoluteDeviation(app.EMGp1);
            
                                        MAVs = [MAV, MAV1];
                                        MAV = max(MAVs);
                                        
                                        if MAV > app.Promedio
                                            if app.pasado - 300 < 0
                                                app.menor = app.pasado - 300;
                                                app.menor = 3000-app.menor;
                                                app.EMG_ventana = [app.buffer(1, app.menor:3000), app.buffer(1, 1:size(app.EMGp,2) + 300)];
                                                app.EMG_ventana = detecta_EMG(app.EMG_ventana,1000,500,100,1.5,0);
            
                                                app.EMG_ventana1 = [app.buffer1(1, app.menor:3000), app.buffer1(1, 1:size(app.EMGp1,2) + 300)];
                                                app.EMG_ventana1 = detecta_EMG(app.EMG_ventana1,1000,500,100,1.5,0);

                                                y = app.EMG_ventana';
                                                y1 = app.EMG_ventana1';
            
                                                app.EMG_s1 = y(1,1:167);
                                                app.EMG_s2 = y(1,168:334);
                                                app.EMG_s3 = y(1,335:500);
                        
                                                app.EMG_s1_2 = y1(1,1:167);
                                                app.EMG_s2_2 = y1(1,168:334);
                                                app.EMG_s3_2 = y1(1,335:500);

                                                ZC1_1 = jZeroCrossing(app.EMG_s1,0.1);
                                                ZC1_2 = jZeroCrossing(app.EMG_s2,0.1);
                                                ZC1_3 = jZeroCrossing(app.EMG_s3,0.1);
                                                ZC2_1 = jZeroCrossing(app.EMG_s1_2,0.1);
                                                ZC2_2 = jZeroCrossing(app.EMG_s2_2,0.1);
                                                ZC2_3 = jZeroCrossing(app.EMG_s3_2,0.1);

                                                MAV1_1 = jMeanAbsoluteDeviation(app.EMG_s1);
                                                MAV1_2 = jMeanAbsoluteDeviation(app.EMG_s2);
                                                MAV1_3 = jMeanAbsoluteDeviation(app.EMG_s3);
                                                MAV2_1 = jMeanAbsoluteDeviation(app.EMG_s1_2);
                                                MAV2_2 = jMeanAbsoluteDeviation(app.EMG_s2_2);
                                                MAV2_3 = jMeanAbsoluteDeviation(app.EMG_s3_2);
                                                app.sig1 = [ZC1_1,ZC1_2,ZC1_3,ZC2_1,ZC2_2,ZC2_3, MAV1_1, MAV1_2, MAV1_3, MAV2_1, MAV2_2, MAV2_3];
                                                Epocas = size(y,2) ; %se toma el tamano del buffer obtenido
                                                L = Epocas; %longitud del vector epocas
                                                
                                                t=linspace(0, Epocas/1000, L);
                           
                                                ylim(app.figFpz_2, [-3.3 3.3]);
                                                Epocas1 = size(y1,2) ; %se toma el tamano del buffer obtenido
                                                L1 = Epocas1; %longitud del vector epocas
                                                
                                                t1=linspace(0, Epocas1/1000, L1);
                                                h1 = plot(app.figFpz, t, y, 'Color','r'); %se plotea esa muestra
                                                h2 = plot(app.figFpz_2, t1, y1, 'Color','b'); %se plotea esa muestra
                                                [x, y] = app.BEntre.predictFcn(app.sig1);
                                                

                                            elseif app.l + 300 > 3000
                                                app.mayor = app.l + 300;
                                                app.mayor = app.mayor-3000;
                                                app.EMG_ventana = [app.buffer(1, (size(app.EMGp,2) + 300):3000),app.buffer(1, 1:app.mayor)];
                                                app.EMG_ventana = detecta_EMG(app.EMG_ventana,1000,500,100,1.5,0);
                                                
            
                                                app.EMG_ventana1 = [app.buffer1(1, (size(app.EMGp1,2) + 300):3000),app.buffer1(1, 1:app.mayor)];
                                                app.EMG_ventana1 = detecta_EMG(app.EMG_ventana1,1000,500,100,1.5,0);
                                                y = app.EMG_ventana';
                                                y1 = app.EMG_ventana1';
            
                                                app.EMG_s1 = y(1,1:167);
                                                app.EMG_s2 = y(1,168:334);
                                                app.EMG_s3 = y(1,335:500);
                        
                                                app.EMG_s1_2 = y1(1,1:167);
                                                app.EMG_s2_2 = y1(1,168:334);
                                                app.EMG_s3_2 = y1(1,335:500);
                                                

                                                ZC1_1 = jZeroCrossing(app.EMG_s1,0.1);
                                                ZC1_2 = jZeroCrossing(app.EMG_s2,0.1);
                                                ZC1_3 = jZeroCrossing(app.EMG_s3,0.1);
                                                ZC2_1 = jZeroCrossing(app.EMG_s1_2,0.1);
                                                ZC2_2 = jZeroCrossing(app.EMG_s2_2,0.1);
                                                ZC2_3 = jZeroCrossing(app.EMG_s3_2,0.1);

                                                MAV1_1 = jMeanAbsoluteDeviation(app.EMG_s1);
                                                MAV1_2 = jMeanAbsoluteDeviation(app.EMG_s2);
                                                MAV1_3 = jMeanAbsoluteDeviation(app.EMG_s3);
                                                MAV2_1 = jMeanAbsoluteDeviation(app.EMG_s1_2);
                                                MAV2_2 = jMeanAbsoluteDeviation(app.EMG_s2_2);
                                                MAV2_3 = jMeanAbsoluteDeviation(app.EMG_s3_2);
                                                app.sig1 = [ZC1_1,ZC1_2,ZC1_3,ZC2_1,ZC2_2,ZC2_3, MAV1_1, MAV1_2, MAV1_3, MAV2_1, MAV2_2, MAV2_3];
                                                Epocas = size(y,2) ; %se toma el tamano del buffer obtenido
                                                L = Epocas; %longitud del vector epocas
                                                
                                                t=linspace(0, Epocas/1000, L);
                           
                                                ylim(app.figFpz_2, [-3.3 3.3]);
                                                Epocas1 = size(y1,2) ; %se toma el tamano del buffer obtenido
                                                L1 = Epocas1; %longitud del vector epocas
                                                
                                                t1=linspace(0, Epocas1/1000, L1);
                                                h1 = plot(app.figFpz, t, y, 'Color','r'); %se plotea esa muestra
                                                h2 = plot(app.figFpz_2, t1, y1, 'Color','b'); %se plotea esa muestra
                                                [x, y] = app.BEntre.predictFcn(app.sig1);

                                            else
                                                app.EMG_ventana =  app.buffer(1, app.pasado - 300: app.l + 300);
                                                app.EMG_ventana = detecta_EMG(app.EMG_ventana,1000,500,100,1.5,0);
            
                                                app.EMG_ventana1 =  app.buffer1(1, app.pasado1 - 300: app.l1 + 300);
                                                app.EMG_ventana1 = detecta_EMG(app.EMG_ventana1,1000,500,100,1.5,0);
                                                y = app.EMG_ventana';
                                                y1 = app.EMG_ventana1';

            
                                                app.EMG_s1 = y(1,1:167);
                                                app.EMG_s2 = y(1,168:334);
                                                app.EMG_s3 = y(1,335:500);
                        
                                                app.EMG_s1_2 = y1(1,1:167);
                                                app.EMG_s2_2 = y1(1,168:334);
                                                app.EMG_s3_2 = y1(1,335:500);
                                                
                                                ZC1_1 = jZeroCrossing(app.EMG_s1,0.1);
                                                ZC1_2 = jZeroCrossing(app.EMG_s2,0.1);
                                                ZC1_3 = jZeroCrossing(app.EMG_s3,0.1);
                                                ZC2_1 = jZeroCrossing(app.EMG_s1_2,0.1);
                                                ZC2_2 = jZeroCrossing(app.EMG_s2_2,0.1);
                                                ZC2_3 = jZeroCrossing(app.EMG_s3_2,0.1);

                                                MAV1_1 = jMeanAbsoluteDeviation(app.EMG_s1);
                                                MAV1_2 = jMeanAbsoluteDeviation(app.EMG_s2);
                                                MAV1_3 = jMeanAbsoluteDeviation(app.EMG_s3);
                                                MAV2_1 = jMeanAbsoluteDeviation(app.EMG_s1_2);
                                                MAV2_2 = jMeanAbsoluteDeviation(app.EMG_s2_2);
                                                MAV2_3 = jMeanAbsoluteDeviation(app.EMG_s3_2);
                                                app.sig1 = [ZC1_1,ZC1_2,ZC1_3,ZC2_1,ZC2_2,ZC2_3, MAV1_1, MAV1_2, MAV1_3, MAV2_1, MAV2_2, MAV2_3];
                                                ylim(app.figFpz, [-3.3 3.3]);
                                                Epocas = size(y,2) ; %se toma el tamano del buffer obtenido
                                                L = Epocas; %longitud del vector epocas
                                                
                                                t=linspace(0, Epocas/1000, L);
                           
                                                ylim(app.figFpz_2, [-3.3 3.3]);
                                                Epocas1 = size(y1,2) ; %se toma el tamano del buffer obtenido
                                                L1 = Epocas1; %longitud del vector epocas
                                                
                                                t1=linspace(0, Epocas1/1000, L1);
                                                h1 = plot(app.figFpz, t, y, 'Color','r'); %se plotea esa muestra
                                                h2 = plot(app.figFpz_2, t1, y1, 'Color','b'); %se plotea esa muestra
                                                [x, y] = app.BEntre.predictFcn(app.sig1);
                                                
                                            end
                                            
                                            
                                        end
                                        if  MAV1 > app.Promedio2
                                            if app.pasado - 300 < 0
                                                app.menor = app.pasado - 300;
                                                app.menor = 3000-app.menor;
                                                app.EMG_ventana = [app.buffer(1, app.menor:3000), app.buffer(1, 1:size(app.EMGp,2) + 300)];
                                                app.EMG_ventana = detecta_EMG(app.EMG_ventana,1000,500,100,1.5,0);
            
                                                app.EMG_ventana1 = [app.buffer1(1, app.menor:3000), app.buffer1(1, 1:size(app.EMGp1,2) + 300)];
                                                app.EMG_ventana1 = detecta_EMG(app.EMG_ventana1,1000,500,100,1.5,0);
                                                y = app.EMG_ventana';
                                                y1 = app.EMG_ventana1';
            
                                                app.EMG_s1 = y(1,1:167);
                                                app.EMG_s2 = y(1,168:334);
                                                app.EMG_s3 = y(1,335:500);
                        
                                                app.EMG_s1_2 = y1(1,1:167);
                                                app.EMG_s2_2 = y1(1,168:334);
                                                app.EMG_s3_2 = y1(1,335:500);
                                                

                                                ZC1_1 = jZeroCrossing(app.EMG_s1,0.1);
                                                ZC1_2 = jZeroCrossing(app.EMG_s2,0.1);
                                                ZC1_3 = jZeroCrossing(app.EMG_s3,0.1);
                                                ZC2_1 = jZeroCrossing(app.EMG_s1_2,0.1);
                                                ZC2_2 = jZeroCrossing(app.EMG_s2_2,0.1);
                                                ZC2_3 = jZeroCrossing(app.EMG_s3_2,0.1);

                                                MAV1_1 = jMeanAbsoluteDeviation(app.EMG_s1);
                                                MAV1_2 = jMeanAbsoluteDeviation(app.EMG_s2);
                                                MAV1_3 = jMeanAbsoluteDeviation(app.EMG_s3);
                                                MAV2_1 = jMeanAbsoluteDeviation(app.EMG_s1_2);
                                                MAV2_2 = jMeanAbsoluteDeviation(app.EMG_s2_2);
                                                MAV2_3 = jMeanAbsoluteDeviation(app.EMG_s3_2);

                                                app.sig1 = [ZC1_1,ZC1_2,ZC1_3,ZC2_1,ZC2_2,ZC2_3, MAV1_1, MAV1_2, MAV1_3, MAV2_1, MAV2_2, MAV2_3];
                                                [x, y] = app.BEntre.predictFcn(app.sig1);
                                                Epocas = size(y,2) ; %se toma el tamano del buffer obtenido
                                                L = Epocas; %longitud del vector epocas
                                                
                                                t=linspace(0, Epocas/1000, L);
                           
                                                ylim(app.figFpz_2, [-3.3 3.3]);
                                                Epocas1 = size(y1,2) ; %se toma el tamano del buffer obtenido
                                                L1 = Epocas1; %longitud del vector epocas
                                                
                                                t1=linspace(0, Epocas1/1000, L1);
                                                h1 = plot(app.figFpz, t, y, 'Color','r'); %se plotea esa muestra
                                                h2 = plot(app.figFpz_2, t1, y1, 'Color','b'); %se plotea esa muestra

                                            elseif app.l + 300 > 3000
                                                app.mayor = app.l + 300;
                                                app.mayor = app.mayor-3000;
                                                app.EMG_ventana = [app.buffer(1, (size(app.EMGp,2) + 300):3000),app.buffer(1, 1:app.mayor)];
                                                app.EMG_ventana = detecta_EMG(app.EMG_ventana,1000,500,100,1.5,0);
            
                                                app.EMG_ventana1 = [app.buffer1(1, (size(app.EMGp1,2) + 300):3000),app.buffer1(1, 1:app.mayor)];
                                                app.EMG_ventana1 = detecta_EMG(app.EMG_ventana1,1000,500,100,1.5,0);
                                                y = app.EMG_ventana';
                                                y1 = app.EMG_ventana1';
            
                                                app.EMG_s1 = y(1,1:167);
                                                app.EMG_s2 = y(1,168:334);
                                                app.EMG_s3 = y(1,335:500);
                        
                                                app.EMG_s1_2 = y1(1,1:167);
                                                app.EMG_s2_2 = y1(1,168:334);
                                                app.EMG_s3_2 = y1(1,335:500);
                                                
                                                Epocas = size(y,2) ; %se toma el tamano del buffer obtenido
                                                L = Epocas; %longitud del vector epocas
                                                
                                                t=linspace(0, Epocas/1000, L);
                           
                                                ylim(app.figFpz_2, [-3.3 3.3]);
                                                Epocas1 = size(y1,2) ; %se toma el tamano del buffer obtenido
                                                L1 = Epocas1; %longitud del vector epocas
                                                
                                                t1=linspace(0, Epocas1/1000, L1);
                                                h1 = plot(app.figFpz, t, y, 'Color','r'); %se plotea esa muestra
                                                h2 = plot(app.figFpz_2, t1, y1, 'Color','b'); %se plotea esa muestra

                                                ZC1_1 = jZeroCrossing(app.EMG_s1,0.1);
                                                ZC1_2 = jZeroCrossing(app.EMG_s2,0.1);
                                                ZC1_3 = jZeroCrossing(app.EMG_s3,0.1);
                                                ZC2_1 = jZeroCrossing(app.EMG_s1_2,0.1);
                                                ZC2_2 = jZeroCrossing(app.EMG_s2_2,0.1);
                                                ZC2_3 = jZeroCrossing(app.EMG_s3_2,0.1);

                                                MAV1_1 = jMeanAbsoluteDeviation(app.EMG_s1);
                                                MAV1_2 = jMeanAbsoluteDeviation(app.EMG_s2);
                                                MAV1_3 = jMeanAbsoluteDeviation(app.EMG_s3);
                                                MAV2_1 = jMeanAbsoluteDeviation(app.EMG_s1_2);
                                                MAV2_2 = jMeanAbsoluteDeviation(app.EMG_s2_2);
                                                MAV2_3 = jMeanAbsoluteDeviation(app.EMG_s3_2);

                                                app.sig1 = [ZC1_1,ZC1_2,ZC1_3,ZC2_1,ZC2_2,ZC2_3, MAV1_1, MAV1_2, MAV1_3, MAV2_1, MAV2_2, MAV2_3];
                                                [x, y] = app.BEntre.predictFcn(app.sig1);
                                            else
                                                app.EMG_ventana =  app.buffer(1, app.pasado - 300: app.l + 300);
                                                app.EMG_ventana = detecta_EMG(app.EMG_ventana,1000,500,100,1.5,0);
            
                                                app.EMG_ventana1 =  app.buffer1(1, app.pasado1 - 300: app.l1 + 300);
                                                app.EMG_ventana1 = detecta_EMG(app.EMG_ventana1,1000,500,100,1.5,0);
                                                y = app.EMG_ventana';
                                                y1 = app.EMG_ventana1';
                                                Epocas = size(y,2) ; %se toma el tamano del buffer obtenido
                                                L = Epocas; %longitud del vector epocas
                                                
                                                t=linspace(0, Epocas/1000, L);
                           
                                                ylim(app.figFpz_2, [-3.3 3.3]);
                                                Epocas1 = size(y1,2) ; %se toma el tamano del buffer obtenido
                                                L1 = Epocas1; %longitud del vector epocas
                                                
                                                t1=linspace(0, Epocas1/1000, L1);
                                                h1 = plot(app.figFpz, t, y, 'Color','r'); %se plotea esa muestra
                                                h2 = plot(app.figFpz_2, t1, y1, 'Color','b'); %se plotea esa muestra
            
                                                app.EMG_s1 = y(1,1:167);
                                                app.EMG_s2 = y(1,168:334);
                                                app.EMG_s3 = y(1,335:500);
                        
                                                app.EMG_s1_2 = y1(1,1:167);
                                                app.EMG_s2_2 = y1(1,168:334);
                                                app.EMG_s3_2 = y1(1,335:500);

                                                ZC1_1 = jZeroCrossing(app.EMG_s1,0.1);
                                                ZC1_2 = jZeroCrossing(app.EMG_s2,0.1);
                                                ZC1_3 = jZeroCrossing(app.EMG_s3,0.1);
                                                ZC2_1 = jZeroCrossing(app.EMG_s1_2,0.1);
                                                ZC2_2 = jZeroCrossing(app.EMG_s2_2,0.1);
                                                ZC2_3 = jZeroCrossing(app.EMG_s3_2,0.1);

                                                MAV1_1 = jMeanAbsoluteDeviation(app.EMG_s1);
                                                MAV1_2 = jMeanAbsoluteDeviation(app.EMG_s2);
                                                MAV1_3 = jMeanAbsoluteDeviation(app.EMG_s3);
                                                MAV2_1 = jMeanAbsoluteDeviation(app.EMG_s1_2);
                                                MAV2_2 = jMeanAbsoluteDeviation(app.EMG_s2_2);
                                                MAV2_3 = jMeanAbsoluteDeviation(app.EMG_s3_2);

                                                app.sig1 = [ZC1_1,ZC1_2,ZC1_3,ZC2_1,ZC2_2,ZC2_3, MAV1_1, MAV1_2, MAV1_3, MAV2_1, MAV2_2, MAV2_3];
                                                [x, y] = app.BEntre.predictFcn(app.sig1);
                                                
                                            end
                                        end
                                        
                                    end
                                end
                                app.pasado = app.l;
                                app.pasado1 = app.l1;


                                    app.ClasificacinGauge.Value = x;
                                    valor = x;

                                    switch valor
                                        case 0
                                            Pulgar = 170;
                                            Metacarpo = 160;
                                            Indice = 190;
                                            Medio = 190;
                                            Anular = 190;
                                            Menique = 190;
                            
                                        case 1
                                            Pulgar = 170;
                                            Metacarpo = 160;
                                            Indice = 127;
                                            Medio = 190;
                                            Anular = 190;
                                            Menique = 190;
                                        case 2
                                            Pulgar = 170;
                                            Metacarpo = 160;
                                            Indice = 127;
                                            Medio = 127;
                                            Anular = 190;
                                            Menique = 190;
                                        case 3
                                            Pulgar = 170;
                                            Metacarpo = 160;
                                            Indice = 190;
                                            Medio = 127;
                                            Anular = 127;
                                            Menique = 127;
                                        case 4
                                            Pulgar = 170;
                                            Metacarpo = 160;
                                            Indice = 127;
                                            Medio = 127;
                                            Anular = 127;
                                            Menique = 127;
                                        otherwise
                                            Pulgar = 127;
                                            Metacarpo = 127;
                                            Indice = 127;
                                            Medio = 127;
                                            Anular = 127;
                                            Menique = 127;
                            
                                    end
                                    MATRIZ_DATA = [255, Antebrazo, Muneca_DI, Muneca_AA, Pulgar, Metacarpo, Indice, Medio, Anular, Menique];
                                    write(app.PuertoSerialMano,MATRIZ_DATA,"uint8");
                                end
                            %end
                    end
                end
                    
           
        end

        % Button pushed function: SeleccionarArchivomatButton
        function SeleccionarArchivomatButtonPushed(app, event)
            [file, path] = uigetfile('*.mat', 'Seleccione el archivo .MAT');
            if isequal(file, 0)
                disp('Usuario seleccionó CANCELAR');
                return;
            else
                disp(['Selección: ', fullfile(path, file)]);
                mat = load(fullfile(path, file)); % Cargar el archivo .mat
            end

            if isfield(mat, 'BaseCuartoTOT') 
                app.loadedData = mat; % Almacenar datos para uso posterior
                disp('Datos cargados exitosamente. Listos para ser aplicados.');
            else
                disp('El archivo no contiene las variables necesarias.');
            end

            app.BDentrenamiento = app.loadedData.BaseCuartoTOT;
            app.SVMCheckBox.Enable = "on";
            app.KNNCheckBox.Enable = "on";
            app.ANNCheckBox.Enable = "on";
            app.SeleccionartodosCheckBox.Enable = "on";
        end

        % Value changed function: SVMCheckBox
        function SVMCheckBoxValueChanged(app, event)
            value = app.SVMCheckBox.Value;
            if value == 1
                if app.SensorCheckBox.Value == 1
                    [app.entreSVM, valor] = SVMv2_1S(app.BDentrenamiento);
                    valor = valor*100;
                    app.PorcentajedeValidacinEditField.Value = valor;
                elseif app.SensoresCheckBox.Value == 1
                    [app.entreSVM, valor] = SVM2s(app.BDentrenamiento);
                    valor = valor*100;
                    app.PorcentajedeValidacinEditField.Value = valor;
                end    
                app.SeleccionarMtododeClasificacinButton.Enable = "on";
            else
                clear app.entreSVM
                app.PorcentajedeValidacinEditField.Value = 0;
                app.SeleccionarMtododeClasificacinButton.Enable = "off";
            end
        end

        % Value changed function: KNNCheckBox
        function KNNCheckBoxValueChanged(app, event)
            value = app.KNNCheckBox.Value;
            if value == 1
                if app.SensorCheckBox.Value == 1
                    [app.entreKNN, valor] = KNNv2_1S(app.BDentrenamiento);
                    valor = valor*100;
                    app.PorcentajedeValidacinEditField_2.Value = valor;
                elseif app.SensoresCheckBox.Value == 1
                    [app.entreKNN, valor] = KNN2s(app.BDentrenamiento);
                    valor = valor*100;
                    app.PorcentajedeValidacinEditField_2.Value = valor;
                end  
                app.SeleccionarMtododeClasificacinButton.Enable = "on";
            else
                clear app.entreKNN
                app.PorcentajedeValidacinEditField_2.Value = 0;
                app.SeleccionarMtododeClasificacinButton.Enable = "off";
            end
        end

        % Value changed function: ANNCheckBox
        function ANNCheckBoxValueChanged(app, event)
            value = app.ANNCheckBox.Value;
            if value == 1
                if app.SensorCheckBox.Value == 1
                    [app.entreANN, valor] = ANNv2_1S(app.BDentrenamiento);
                    valor = valor*100;
                    app.PorcentajedeValidacinEditField_3.Value = valor;
                elseif app.SensoresCheckBox.Value == 1
                    [app.entreANN, valor] = ANN2s(app.BDentrenamiento);
                    valor = valor*100;
                    app.PorcentajedeValidacinEditField_3.Value = valor;
                end          
                app.SeleccionarMtododeClasificacinButton.Enable = "on";
            else
                clear app.entreANN
                app.PorcentajedeValidacinEditField_3.Value = 0;
                app.SeleccionarMtododeClasificacinButton.Enable = "off";
            end
        end

        % Value changed function: SeleccionartodosCheckBox
        function SeleccionartodosCheckBoxValueChanged(app, event)
            value = app.SeleccionartodosCheckBox.Value;
            if value == 1
                if app.SensorCheckBox.Value == 1
                    [app.entreSVM, valor] = SVMv2_1S(app.BDentrenamiento);
                    valor = valor*100;
                    app.PorcentajedeValidacinEditField.Value = valor;
                    [app.entreKNN, valor] = KNNv2_1S(app.BDentrenamiento);
                    valor = valor*100;
                    app.PorcentajedeValidacinEditField_2.Value = valor;
                    [app.entreANN, valor] = ANNv2_1S(app.BDentrenamiento);
                    valor = valor*100;
                    app.PorcentajedeValidacinEditField_3.Value = valor;
                elseif app.SensoresCheckBox.Value == 1
                    [app.entreSVM, valor] = SVM2s(app.BDentrenamiento);
                    valor = valor*100;
                    app.PorcentajedeValidacinEditField.Value = valor;
                    [app.entreKNN, valor] = KNN2s(app.BDentrenamiento);
                    valor = valor*100;
                    app.PorcentajedeValidacinEditField_2.Value = valor;
                    [app.entreANN, valor] = ANN2s(app.BDentrenamiento);
                    valor = valor*100;
                    app.PorcentajedeValidacinEditField_3.Value = valor;
                end
                app.SeleccionarMtododeClasificacinButton.Enable = "on";
            else
                app.SVMCheckBox.Value = 0;
                clear app.entreSVM
                app.PorcentajedeValidacinEditField.Value = 0;
                app.KNNCheckBox.Value = 0;
                clear app.entreKNN
                app.PorcentajedeValidacinEditField_2.Value = 0;
                app.ANNCheckBox.Value = 0;
                clear app.entreANN
                app.PorcentajedeValidacinEditField_3.Value = 0;
                app.SeleccionarMtododeClasificacinButton.Enable = "off";
            end
        end

        % Button pushed function: SeleccionarMtododeClasificacinButton
        function SeleccionarMtododeClasificacinButtonPushed(app, event)
            if app.PorcentajedeValidacinEditField.Value ~= 0 || app.PorcentajedeValidacinEditField_2.Value ~= 0 ||app.PorcentajedeValidacinEditField_3.Value ~= 0
                valor = [app.PorcentajedeValidacinEditField.Value, app.PorcentajedeValidacinEditField_2.Value, app.PorcentajedeValidacinEditField_3.Value];
                [mayor, indice] = max(valor);
                switch indice
                    case 1
                        app.BEntre = app.entreSVM;
                        app.Lamp_2.Enable = "on";
                        app.Lamp_3.Enable = "off";
                        app.Lamp_4.Enable = "off";
                    case 2
                        app.BEntre = app.entreKNN;
                        app.Lamp_2.Enable = "off";
                        app.Lamp_3.Enable = "on";
                        app.Lamp_4.Enable = "off";
                    case 3
                        app.BEntre = app.entreANN;
                        app.Lamp_2.Enable = "off";
                        app.Lamp_3.Enable = "off";
                        app.Lamp_4.Enable = "on";
                end
            else
                uialert(app.UIFigure, 'No se ha seleccionado ningún método, por favor seleccione una opción.', 'Error al seleccionar método de clasificación.', ...
                    'Icon', 'error');

            end
        end

        % Callback function
        function ButtonPushed(app, event)
            app.banBit = 1;
        end

        % Value changed function: SensorCheckBox
        function SensorCheckBoxValueChanged(app, event)
            value = app.SensorCheckBox.Value;
            if value == 1
                app.Control1sensorCheckBox.Enable = "on";
                app.Control2sensoresCheckBox.Enable = "off";
                app.SensoresCheckBox.Value = false;
                app.SeleccionarArchivomatButton.Enable = "on";
            else
                app.Control2sensoresCheckBox.Enable = "on";
                app.SeleccionarArchivomatButton.Enable = "off";
            end
        end

        % Value changed function: SensoresCheckBox
        function SensoresCheckBoxValueChanged(app, event)
            value = app.SensoresCheckBox.Value;
            if value == 1
                app.Control2sensoresCheckBox.Enable = "on";
                app.Control1sensorCheckBox.Enable = "off";
                app.SensorCheckBox.Value = false;
                app.SeleccionarArchivomatButton.Enable = "on";
            else
                app.Control1sensorCheckBox.Enable = "on";
                app.SeleccionarArchivomatButton.Enable = "off";
            end
        end

        % Button pushed function: CalibracionButton
        function CalibracionButtonPushed(app, event)
            MAVs = [];
            MAVs1 = [];
            if app.SensorCheckBox.Value == 1
                for i = 1:20
                    data = read(app.Bit1);
                    pause(0.5);
                    EMG_raw = data(:,6);
                    EMG = EMG_raw*(3.3/1024);
                
                    data_fP = filter(app.F_pb, EMG');                          %Aplicar filtro pasa bandas
                    data_nP = filter(app.F_notch, data_fP);
                
                    if size(data_nP,2) >= 500 && size(data_nP,2) <= 600
                        EMGpP = data_nP(1, 60:end);
                        MAV = jMeanAbsoluteDeviation(EMGpP);
                        MAVs = [MAVs, MAV];
                
                    end
                    
                end
                app.Promedio = max(MAVs) + 0.015;
                app.CalibradoLamp.Enable = "on";
            end

            if app.SensoresCheckBox.Value == 1
                for i = 1:20
                    data = read(app.Bit1);
                    data1 = read(app.Bit2);
                    pause(0.5);
                    EMG_raw = data(:,6);
                    EMG = EMG_raw*(3.3/1024);
                    EMG_raw1 = data1(:,6);
                    EMG1 = EMG_raw1*(3.3/1024);
                
                    data_fP = filter(app.F_pb, EMG');                          %Aplicar filtro pasa bandas
                    data_nP = filter(app.F_notch, data_fP);

                    data_fP1 = filter(app.F_pb, EMG1');                          %Aplicar filtro pasa bandas
                    data_nP1 = filter(app.F_notch, data_fP1);
                
                    if size(data_nP,2) >= 500 && size(data_nP,2) <= 600
                        EMGpP = data_nP(1, 60:end);
                        MAV = jMeanAbsoluteDeviation(EMGpP);
                        MAVs = [MAVs, MAV];

                        EMGpP1 = data_nP1(1, 60:end);
                        MAV1 = jMeanAbsoluteDeviation(EMGpP1);
                        MAVs1 = [MAVs1, MAV1];
                
                    end
                    
                end

                app.Promedio = max(MAVs) + 0.015;
                app.Promedio2 = max(MAVs1) + 0.015;
                app.CalibradoLamp.Enable = "on";
            
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.figFpz = uiaxes(app.UIFigure);
            title(app.figFpz, 'Señal EMG ')
            xlabel(app.figFpz, 't')
            ylabel(app.figFpz, 'V')
            zlabel(app.figFpz, 'Z')
            app.figFpz_2 = uiaxes(app.UIFigure);
            title(app.figFpz_2, 'Señal EMG ')
            xlabel(app.figFpz_2, 't')
            ylabel(app.figFpz_2, 'V')
            zlabel(app.figFpz_2, 'Z')
            app.Panel = uipanel(app.UIFigure);
            app.ControldeManoAnimatrnicaUVGLabel = uilabel(app.Panel);
            app.ControldeManoAnimatrnicaUVGLabel.Text = 'Control de Mano Animatrónica UVG';
            app.BITalino1LampLabel = uilabel(app.Panel);
            app.BITalino1LampLabel.Text = 'BITalino 1';
            app.BITalino1Lamp = uilamp(app.Panel);
            app.BITalino1Lamp.Enable = 'off';
            app.MyoWareLampLabel = uilabel(app.Panel);
            app.MyoWareLampLabel.Text = 'MyoWare';
            app.MyoWareLamp = uilamp(app.Panel);
            app.MyoWareLamp.Enable = 'off';
            app.ManoAnimatrnicaLampLabel = uilabel(app.Panel);
            app.ManoAnimatrnicaLampLabel.Text = 'Mano Animatrónica';
            app.ManoAnimatrnicaLamp = uilamp(app.Panel);
            app.ManoAnimatrnicaLamp.Enable = 'off';
            app.BITalino2LampLabel = uilabel(app.Panel);
            app.BITalino2LampLabel.Text = 'BITalino 2';
            app.BITalino2Lamp = uilamp(app.Panel);
            app.BITalino2Lamp.Enable = 'off';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InstruccionesTab = uitab(app.TabGroup);
            app.InstruccionesTab.Title = 'Instrucciones';
            app.InstruccionesparaelusodelaInterfazLabel = uilabel(app.InstruccionesTab);
            app.InstruccionesparaelusodelaInterfazLabel.Text = 'Instrucciones para el uso de la Interfaz';
            app.InstruccionesparausoconMyoWareLabel = uilabel(app.InstruccionesTab);
            app.InstruccionesparausoconMyoWareLabel.Text = 'Instrucciones para uso con MyoWare';
            app.InstruccionesparausoconBitalinosLabel = uilabel(app.InstruccionesTab);
            app.InstruccionesparausoconBitalinosLabel.Text = 'Instrucciones para uso con Bitalino(s)';
            app.ModoBinarioLabel = uilabel(app.InstruccionesTab);
            app.ModoBinarioLabel.Text = '(Modo Binario)';
            app.Modo1y2SensoresLabel = uilabel(app.InstruccionesTab);
            app.Modo1y2SensoresLabel.Text = '(Modo 1 y 2 Sensores)';
            app.Label = uilabel(app.InstruccionesTab);
            app.Label.Text = '1. Ir a pestaña "Panel de control".';
            app.Label_2 = uilabel(app.InstruccionesTab);
            app.Label_2.Text = '2. Seleccionar modo binario.';
            app.Label_3 = uilabel(app.InstruccionesTab);
            app.Label_3.Text = '3. Seleccionar puerto COM para Arduino.';
            app.Label_4 = uilabel(app.InstruccionesTab);
            app.Label_4.Text = '4. Realizar conexión con microcontrolador.';
            app.Label_5 = uilabel(app.InstruccionesTab);
            app.Label_5.Text = '5. Seleccionar puerto COM para la mano animatrónica.';
            app.Label_6 = uilabel(app.InstruccionesTab);
            app.Label_6.Text = '6. Realizar conexión con la mano animatrónica.';
            app.Label_7 = uilabel(app.InstruccionesTab);
            app.Label_7.Text = '7. Presionar switch de iniciar control';
            app.Label_8 = uilabel(app.InstruccionesTab);
            app.Label_8.Text = '1. Ir a pestaña "Entrenamiento".';
            app.Label_9 = uilabel(app.InstruccionesTab);
            app.Label_9.Text = '2. Seleccionar número de sensores.';
            app.Label_10 = uilabel(app.InstruccionesTab);
            app.Label_10.Text = '3. Seleccionar archivo de entrenamiento.';
            app.Label_11 = uilabel(app.InstruccionesTab);
            app.Label_11.Text = '';
            app.Label_15 = uilabel(app.InstruccionesTab);
            app.Label_15.Text = '4. Seleccionar método de clasificación deseado.';
            app.Label_16 = uilabel(app.InstruccionesTab);
            app.Label_16.Text = '5. Presionar botón de selección de método.';
            app.Label_17 = uilabel(app.InstruccionesTab);
            app.Label_17.Text = '6. Ir a pestaña "Panel de control".';
            app.Label_18 = uilabel(app.InstruccionesTab);
            app.Label_18.Text = '7. Seleccionar modo de uso';
            app.Label_19 = uilabel(app.InstruccionesTab);
            app.Label_19.Text = '8. Realizar conexión con Bitalino(s)';
            app.Label_20 = uilabel(app.InstruccionesTab);
            app.Label_20.Text = '(Utilizar nombre de Bitalino, que aparece en la';
            app.Label_21 = uilabel(app.InstruccionesTab);
            app.Label_21.Text = 'conexión Bluetooth de la computadora)';
            app.Label_22 = uilabel(app.InstruccionesTab);
            app.Label_22.Text = '9. Realizar conexión con Arduino.';
            app.Label_23 = uilabel(app.InstruccionesTab);
            app.Label_23.Text = '10. Realizar conexión con mano animatrónica.';
            app.Label_24 = uilabel(app.InstruccionesTab);
            app.Label_24.Text = '11. Presionar switch de iniciar control.';
            app.EntrenamientoTab = uitab(app.TabGroup);
            app.EntrenamientoTab.Title = 'Entrenamiento';
            app.SeleccinArchivodeEntrenamientoPanel = uipanel(app.EntrenamientoTab);
            app.SeleccinArchivodeEntrenamientoPanel.Title = 'Selección Archivo de Entrenamiento';
            app.SeleccionarArchivomatButton = uibutton(app.SeleccinArchivodeEntrenamientoPanel, 'push');
            app.SeleccionarArchivomatButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarArchivomatButtonPushed, true);
            app.SeleccionarArchivomatButton.Enable = 'off';
            app.SeleccionarArchivomatButton.Text = 'Seleccionar Archivo .mat';
            app.SeleccindeMtodosdeclasificacinPanel = uipanel(app.EntrenamientoTab);
            app.SeleccindeMtodosdeclasificacinPanel.Title = 'Selección de Métodos de clasificación';
            app.SVMCheckBox = uicheckbox(app.SeleccindeMtodosdeclasificacinPanel);
            app.SVMCheckBox.ValueChangedFcn = createCallbackFcn(app, @SVMCheckBoxValueChanged, true);
            app.SVMCheckBox.Enable = 'off';
            app.SVMCheckBox.Text = 'SVM';
            app.KNNCheckBox = uicheckbox(app.SeleccindeMtodosdeclasificacinPanel);
            app.KNNCheckBox.ValueChangedFcn = createCallbackFcn(app, @KNNCheckBoxValueChanged, true);
            app.KNNCheckBox.Enable = 'off';
            app.KNNCheckBox.Text = 'KNN';
            app.ANNCheckBox = uicheckbox(app.SeleccindeMtodosdeclasificacinPanel);
            app.ANNCheckBox.ValueChangedFcn = createCallbackFcn(app, @ANNCheckBoxValueChanged, true);
            app.ANNCheckBox.Enable = 'off';
            app.ANNCheckBox.Text = 'ANN';
            app.SeleccionartodosCheckBox = uicheckbox(app.SeleccindeMtodosdeclasificacinPanel);
            app.SeleccionartodosCheckBox.ValueChangedFcn = createCallbackFcn(app, @SeleccionartodosCheckBoxValueChanged, true);
            app.SeleccionartodosCheckBox.Enable = 'off';
            app.SeleccionartodosCheckBox.Text = 'Seleccionar todos';
            app.Lamp_2 = uilamp(app.SeleccindeMtodosdeclasificacinPanel);
            app.Lamp_2.Enable = 'off';
            app.Lamp_3 = uilamp(app.SeleccindeMtodosdeclasificacinPanel);
            app.Lamp_3.Enable = 'off';
            app.Lamp_4 = uilamp(app.SeleccindeMtodosdeclasificacinPanel);
            app.Lamp_4.Enable = 'off';
            app.SeleccionarMtododeClasificacinButton = uibutton(app.SeleccindeMtodosdeclasificacinPanel, 'push');
            app.SeleccionarMtododeClasificacinButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarMtododeClasificacinButtonPushed, true);
            app.SeleccionarMtododeClasificacinButton.Enable = 'off';
            app.SeleccionarMtododeClasificacinButton.Text = 'Seleccionar Método de Clasificación';
            app.PorcentajedeValidacinEditFieldLabel = uilabel(app.SeleccindeMtodosdeclasificacinPanel);
            app.PorcentajedeValidacinEditFieldLabel.Text = 'Porcentaje de Validación';
            app.PorcentajedeValidacinEditField = uieditfield(app.SeleccindeMtodosdeclasificacinPanel, 'numeric');
            app.PorcentajedeValidacinEditField.Editable = 'off';
            app.PorcentajedeValidacinEditField_2Label = uilabel(app.SeleccindeMtodosdeclasificacinPanel);
            app.PorcentajedeValidacinEditField_2Label.Text = 'Porcentaje de Validación';
            app.PorcentajedeValidacinEditField_2 = uieditfield(app.SeleccindeMtodosdeclasificacinPanel, 'numeric');
            app.PorcentajedeValidacinEditField_2.Editable = 'off';
            app.PorcentajedeValidacinEditField_3Label = uilabel(app.SeleccindeMtodosdeclasificacinPanel);
            app.PorcentajedeValidacinEditField_3Label.Text = 'Porcentaje de Validación';
            app.PorcentajedeValidacinEditField_3 = uieditfield(app.SeleccindeMtodosdeclasificacinPanel, 'numeric');
            app.PorcentajedeValidacinEditField_3.Editable = 'off';
            app.CantidaddesensoresBITalinoPanel = uipanel(app.EntrenamientoTab);
            app.CantidaddesensoresBITalinoPanel.Title = 'Cantidad de sensores BITalino';
            app.SensorCheckBox = uicheckbox(app.CantidaddesensoresBITalinoPanel);
            app.SensorCheckBox.ValueChangedFcn = createCallbackFcn(app, @SensorCheckBoxValueChanged, true);
            app.SensorCheckBox.Text = '1 Sensor';
            app.SensoresCheckBox = uicheckbox(app.CantidaddesensoresBITalinoPanel);
            app.SensoresCheckBox.ValueChangedFcn = createCallbackFcn(app, @SensoresCheckBoxValueChanged, true);
            app.SensoresCheckBox.Text = '2 Sensores';
            app.PaneldecontrolTab = uitab(app.TabGroup);
            app.PaneldecontrolTab.Title = 'Panel de control';
            app.ConexionesPanel = uipanel(app.PaneldecontrolTab);
            app.ConexionesPanel.Title = 'Conexiones';
            app.EditField_2 = uieditfield(app.ConexionesPanel, 'text');
            app.EditField_2.Value = 'BITalino';
            app.NombreBITalinoLabel = uilabel(app.ConexionesPanel);
            app.NombreBITalinoLabel.Text = 'Nombre BITalino';
            app.ConectarBITalinoSwitch = uiswitch(app.ConexionesPanel, 'slider');
            app.ConectarBITalinoSwitch.ValueChangedFcn = createCallbackFcn(app, @ConectarBITalinoSwitchValueChanged, true);
            app.ConectarBITalinoSwitch.Enable = 'off';
            app.ConectarBITalinoSwitch_3Label = uilabel(app.ConexionesPanel);
            app.ConectarBITalinoSwitch_3Label.Text = 'Conectar BITalino';
            app.EmpezarStreamingSwitch = uiswitch(app.ConexionesPanel, 'slider');
            app.EmpezarStreamingSwitch.ValueChangedFcn = createCallbackFcn(app, @EmpezarStreamingSwitchValueChanged, true);
            app.EmpezarStreamingSwitch.Enable = 'off';
            app.EmpezarStreamingSwitch_2Label = uilabel(app.ConexionesPanel);
            app.EmpezarStreamingSwitch_2Label.Text = 'Empezar Streaming';
            app.PuertoMyowareDropDown_2Label = uilabel(app.ConexionesPanel);
            app.PuertoMyowareDropDown_2Label.Text = 'Puerto Myoware';
            app.PuertoMyowareDropDown_2 = uidropdown(app.ConexionesPanel);
            app.PuertoMyowareDropDown_2.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9', 'COM10', 'COM11', 'COM12', 'COM13', 'COM14', 'COM17', 'COM18'};
            app.PuertoMyowareDropDown_2.ValueChangedFcn = createCallbackFcn(app, @PuertoMyowareDropDown_2ValueChanged, true);
            app.PuertoMyowareDropDown_2.Value = 'COM1';
            app.ConectaraArduinoSwitch_2 = uiswitch(app.ConexionesPanel, 'slider');
            app.ConectaraArduinoSwitch_2.ValueChangedFcn = createCallbackFcn(app, @ConectaraArduinoSwitch_2ValueChanged, true);
            app.ConectaraArduinoSwitch_2.Enable = 'off';
            app.ConectaraArduinoSwitch_2Label = uilabel(app.ConexionesPanel);
            app.ConectaraArduinoSwitch_2Label.Text = 'Conectar a Arduino';
            app.PuertoManoAnimatrnicaDropDownLabel = uilabel(app.ConexionesPanel);
            app.PuertoManoAnimatrnicaDropDownLabel.Text = 'Puerto Mano Animatrónica';
            app.PuertoManoAnimatrnicaDropDown = uidropdown(app.ConexionesPanel);
            app.PuertoManoAnimatrnicaDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9', 'COM10', 'COM11', 'COM12', 'COM13', 'COM14'};
            app.PuertoManoAnimatrnicaDropDown.ValueChangedFcn = createCallbackFcn(app, @PuertoManoAnimatrnicaDropDownValueChanged, true);
            app.PuertoManoAnimatrnicaDropDown.Value = 'COM3';
            app.ConectaraManoAnimatrnicaSwitch = uiswitch(app.ConexionesPanel, 'slider');
            app.ConectaraManoAnimatrnicaSwitch.ValueChangedFcn = createCallbackFcn(app, @ConectaraManoAnimatrnicaSwitchValueChanged, true);
            app.ConectaraManoAnimatrnicaSwitch.Enable = 'off';
            app.ConectaraManoAnimatrnicaSwitchLabel = uilabel(app.ConexionesPanel);
            app.ConectaraManoAnimatrnicaSwitchLabel.Text = 'Conectar a Mano Animatrónica';
            app.EditField_3 = uieditfield(app.ConexionesPanel, 'text');
            app.EditField_3.Value = 'BITalino';
            app.NombreBITalino2Label = uilabel(app.ConexionesPanel);
            app.NombreBITalino2Label.Text = 'Nombre BITalino 2';
            app.ConectarBITalino2Switch = uiswitch(app.ConexionesPanel, 'slider');
            app.ConectarBITalino2Switch.ValueChangedFcn = createCallbackFcn(app, @ConectarBITalino2SwitchValueChanged, true);
            app.ConectarBITalino2Switch.Enable = 'off';
            app.ConectarBITalino2SwitchLabel = uilabel(app.ConexionesPanel);
            app.ConectarBITalino2SwitchLabel.Text = 'Conectar BITalino 2';
            app.EmpezarStreaming2Switch = uiswitch(app.ConexionesPanel, 'slider');
            app.EmpezarStreaming2Switch.ValueChangedFcn = createCallbackFcn(app, @EmpezarStreaming2SwitchValueChanged, true);
            app.EmpezarStreaming2Switch.Enable = 'off';
            app.EmpezarStreamingSwitch_2Label_2 = uilabel(app.ConexionesPanel);
            app.EmpezarStreamingSwitch_2Label_2.Text = 'Empezar Streaming 2';
            app.SeleccindecontrolPanel = uipanel(app.PaneldecontrolTab);
            app.SeleccindecontrolPanel.Title = 'Selección de control';
            app.IniciarControlSwitchLabel = uilabel(app.SeleccindecontrolPanel);
            app.IniciarControlSwitchLabel.Text = 'Iniciar Control';
            app.IniciarControlSwitch = uiswitch(app.SeleccindecontrolPanel, 'slider');
            app.IniciarControlSwitch.ValueChangedFcn = createCallbackFcn(app, @IniciarControlSwitchValueChanged, true);
            app.IniciarControlSwitch.Enable = 'off';
            app.Lamp = uilamp(app.SeleccindecontrolPanel);
            app.Lamp.Enable = 'off';
            app.ControlBinarioCheckBox = uicheckbox(app.SeleccindecontrolPanel);
            app.ControlBinarioCheckBox.ValueChangedFcn = createCallbackFcn(app, @ControlBinarioCheckBoxValueChanged, true);
            app.ControlBinarioCheckBox.Text = 'Control Binario';
            app.Control1sensorCheckBox = uicheckbox(app.SeleccindecontrolPanel);
            app.Control1sensorCheckBox.ValueChangedFcn = createCallbackFcn(app, @Control1sensorCheckBoxValueChanged, true);
            app.Control1sensorCheckBox.Text = 'Control 1 sensor';
            app.Control2sensoresCheckBox = uicheckbox(app.SeleccindecontrolPanel);
            app.Control2sensoresCheckBox.ValueChangedFcn = createCallbackFcn(app, @Control2sensoresCheckBoxValueChanged, true);
            app.Control2sensoresCheckBox.Text = 'Control 2 sensores';
            app.CalibracionButton = uibutton(app.SeleccindecontrolPanel, 'push');
            app.CalibracionButton.ButtonPushedFcn = createCallbackFcn(app, @CalibracionButtonPushed, true);
            app.CalibracionButton.Text = 'Calibracion';
            app.CalibradoLampLabel = uilabel(app.SeleccindecontrolPanel);
            app.CalibradoLampLabel.Text = 'Calibrado';
            app.CalibradoLamp = uilamp(app.SeleccindecontrolPanel);
            app.CalibradoLamp.Enable = 'off';
            app.ClasificacinGaugeLabel = uilabel(app.UIFigure);
            app.ClasificacinGaugeLabel.Text = 'Clasificación';
            app.ClasificacinGauge = uigauge(app.UIFigure, 'linear');
            app.ClasificacinGauge.Limits = [0 5];
            app.ModoBinarioLampLabel = uilabel(app.UIFigure);
            app.ModoBinarioLampLabel.Text = 'Modo Binario';
            app.ModoBinarioLamp = uilamp(app.UIFigure);
            app.ModoBinarioLamp.Enable = 'off';
            app.Modo1SensorLampLabel = uilabel(app.UIFigure);
            app.Modo1SensorLampLabel.Text = 'Modo 1 Sensor';
            app.Modo1SensorLamp = uilamp(app.UIFigure);
            app.Modo1SensorLamp.Enable = 'off';
            app.Modo2sensoresLampLabel = uilabel(app.UIFigure);
            app.Modo2sensoresLampLabel.Text = 'Modo 2 sensores';
            app.Modo2sensoresLamp = uilamp(app.UIFigure);
            app.Modo2sensoresLamp.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz_Control_Actuadores

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end