classdef RecoleccionDeDatosEMGv2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InstruccionesTab                matlab.ui.container.Tab
        Label_7                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        InstruccionesparaelusodelaInterfazLabel  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        PaneldecontrolTab               matlab.ui.container.Tab
        CaracteristicasSeleccionadasPanel  matlab.ui.container.Panel
        CalibrarButton                  matlab.ui.control.Button
        SensoresCheckBox                matlab.ui.control.CheckBox
        SensorCheckBox                  matlab.ui.control.CheckBox
        SeleccionartodoCheckBox         matlab.ui.control.CheckBox
        Lamp_3                          matlab.ui.control.Lamp
        Lamp_2                          matlab.ui.control.Lamp
        NombreBluetoothBitalinoEditField  matlab.ui.control.EditField
        NombreBluetoothBitalinoEditFieldLabel  matlab.ui.control.Label
        EmpezarStreamingSwitch          matlab.ui.control.Switch
        EmpezarStreamingSwitchLabel     matlab.ui.control.Label
        ConectarBitalinoSwitch          matlab.ui.control.Switch
        ConectarBitalinoSwitchLabel     matlab.ui.control.Label
        CrearTablaButton                matlab.ui.control.Button
        MediaCuadraticaCheckBox         matlab.ui.control.CheckBox
        AmplituddeWilsonCheckBox        matlab.ui.control.CheckBox
        VarianzaCheckBox                matlab.ui.control.CheckBox
        PromediodeEnergaCheckBox        matlab.ui.control.CheckBox
        KurtosisCheckBox                matlab.ui.control.CheckBox
        MAVCheckBox                     matlab.ui.control.CheckBox
        ZeroCrossingCheckBox            matlab.ui.control.CheckBox
        GrabacinPanel                   matlab.ui.container.Panel
        NombreBluetoothBitalino2EditField  matlab.ui.control.EditField
        NombreBluetoothBitalino2EditFieldLabel  matlab.ui.control.Label
        EmpezarStreamingSwitch_2        matlab.ui.control.Switch
        EmpezarStreamingSwitch_2Label   matlab.ui.control.Label
        ConectarBitalinoSwitch_2        matlab.ui.control.Switch
        ConectarBitalinoSwitch_2Label   matlab.ui.control.Label
        Lamp_5                          matlab.ui.control.Lamp
        Lamp_4                          matlab.ui.control.Lamp
        TerminarsesinButton             matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        Label_9                         matlab.ui.control.Label
        NmerodemuestrastomadasLabel     matlab.ui.control.Label
        IniciarsesinButton              matlab.ui.control.Button
        NombreDelArchivoEditField       matlab.ui.control.EditField
        NombreDelArchivoEditFieldLabel  matlab.ui.control.Label
        TiempodemuestraatomarEditField  matlab.ui.control.NumericEditField
        TiempodemuestraatomarEditFieldLabel  matlab.ui.control.Label
        GuardarArchivoButton            matlab.ui.control.Button
        LarecoleccinestenPausaLabel     matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        CalibradoLamp                   matlab.ui.control.Lamp
        CalibradoLampLabel              matlab.ui.control.Label
        DesconectadoLamp                matlab.ui.control.Lamp
        DesconectadoLampLabel           matlab.ui.control.Label
        RecoleccindeDatosEMGUVGLabel    matlab.ui.control.Label
        figFpz_2                        matlab.ui.control.UIAxes
        figFpz                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        T=0; % Description
        n=0;
        DataEnBrutoEMG = cell(0,0)
        DataEnBrutoEMG1 = cell(0,0)
        Bit=0;
        Bit1=0;
        F_pb = filtro_pasa_banda(1000,20,450);    %Diseñar filtro pasa banda
        F_notch = filtro_rechaza_banda(1000);     %Diseñar filtro rechaza banda
        data_n=0;
        data_n1=0;
        BanderaParaEmpezar=0;
        NombreBit=0;
        NombreBit1=0;
        flag_recibir1=0;
        flag_recibir2=0;
        flag_recibir11=0;
        flag_recibir21=0;
        flag_terminar=0;
        Pausa=0;
        ctrl2=0;
         time = 0;
            NMuestras=0;
        % Variables detección de señal
        buffer = zeros(1,3000);
        pasado = 1;
        presente = 1;
        l = 1;
        menor = 0;
        mayor = 0;
        EMG_ventana = [];
        EMGp = [];  
        Promedio = 0;
        Promedio2 = 0;

        %Variables detección bitalino 2
        buffer1 = zeros(1,3000);
        pasado1 = 1;
        presente1 = 1;
        l1 = 1;
        menor1 = 0;
        mayor1 = 0;
        EMG_ventana1 = [];
        EMGp1 = [];  
        
        %Variables separación en ventanas
        EMG_s1 = zeros(1,134);
        EMG_s2 = zeros(1,133);
        EMG_s3 = zeros(1,133);

        EMG_s1_2 = zeros(1,134);
        EMG_s2_2 = zeros(1,133);
        EMG_s3_2 = zeros(1,133);

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.Lamp_2.Enable = "off";
            app.Lamp_3.Enable = "off";
            app.Lamp_4.Enable = "off";
            app.Lamp_5.Enable = "off";
            app.CalibradoLamp.Enable = "off";
            
        end

        % Button pushed function: CrearTablaButton
        function CrearTablaButtonPushed(app, event)
            app.BanderaParaEmpezar=0;
            app.ctrl2=0;
            app.T = table()
            if app.SensorCheckBox.Value == 1
                if app.ZeroCrossingCheckBox.Value == 1
                    app.T.ZC1_1 = zeros(0)
                    app.T.ZC1_2 = zeros(0)
                    app.T.ZC1_3 = zeros(0)
                end
                if app.MAVCheckBox.Value == 1
                    app.T.MAV1_1 = zeros(0)
                    app.T.MAV1_2 = zeros(0)
                    app.T.MAV1_3 = zeros(0)
                end
                if app.KurtosisCheckBox.Value == 1
                    app.T.Kurtosis1_1 = zeros(0)
                    app.T.Kurtosis1_2 = zeros(0)
                    app.T.Kurtosis1_3 = zeros(0)
                end
                if app.PromediodeEnergaCheckBox.Value == 1
                    app.T.AverageE1_1 = zeros(0)
                    app.T.AverageE1_2 = zeros(0)
                    app.T.AverageE1_3 = zeros(0)
                end
                
                if app.VarianzaCheckBox.Value == 1
                    app.T.Varianza1_1 = zeros(0)
                    app.T.Varianza1_2 = zeros(0)
                    app.T.Varianza1_3 = zeros(0)
                end
                if app.AmplituddeWilsonCheckBox.Value == 1
                    app.T.AmplitudWilson1_1 = zeros(0)
                    app.T.AmplitudWilson1_2 = zeros(0)
                    app.T.AmplitudWilson1_3 = zeros(0)
                end
                if app.MediaCuadraticaCheckBox.Value == 1
                    app.T.MediaCuadratica1_1 = zeros(0)
                    app.T.MediaCuadratica1_2 = zeros(0)
                    app.T.MediaCuadratica1_3 = zeros(0)
                end
            end
            if app.SensoresCheckBox.Value == 1
                if app.ZeroCrossingCheckBox.Value == 1
                    app.T.ZC1_1 = zeros(0)
                    app.T.ZC1_2 = zeros(0)
                    app.T.ZC1_3 = zeros(0)
                    app.T.ZC2_1 = zeros(0)
                    app.T.ZC2_2 = zeros(0)
                    app.T.ZC2_3 = zeros(0)
                end
                if app.MAVCheckBox.Value == 1
                    app.T.MAV1_1 = zeros(0)
                    app.T.MAV1_2 = zeros(0)
                    app.T.MAV1_3 = zeros(0)
                    app.T.MAV2_1 = zeros(0)
                    app.T.MAV2_2 = zeros(0)
                    app.T.MAV2_3 = zeros(0)
                end
                if app.KurtosisCheckBox.Value == 1
                    app.T.Kurtosis1_1 = zeros(0)
                    app.T.Kurtosis1_2 = zeros(0)
                    app.T.Kurtosis1_3 = zeros(0)
                    app.T.Kurtosis2_1 = zeros(0)
                    app.T.Kurtosis2_2 = zeros(0)
                    app.T.Kurtosis2_3 = zeros(0)
                end
                if app.PromediodeEnergaCheckBox.Value == 1
                    app.T.AverageE1_1 = zeros(0)
                    app.T.AverageE1_2 = zeros(0)
                    app.T.AverageE1_3 = zeros(0)
                    app.T.AverageE2_1 = zeros(0)
                    app.T.AverageE2_2 = zeros(0)
                    app.T.AverageE2_3 = zeros(0)
                end
                
                if app.VarianzaCheckBox.Value == 1
                    app.T.Varianza1_1 = zeros(0)
                    app.T.Varianza1_2 = zeros(0)
                    app.T.Varianza1_3 = zeros(0)
                    app.T.Varianza2_1 = zeros(0)
                    app.T.Varianza2_2 = zeros(0)
                    app.T.Varianza2_3 = zeros(0)
                end
                if app.AmplituddeWilsonCheckBox.Value == 1
                    app.T.AmplitudWilson1_1 = zeros(0)
                    app.T.AmplitudWilson1_2 = zeros(0)
                    app.T.AmplitudWilson1_3 = zeros(0)
                    app.T.AmplitudWilson2_1 = zeros(0)
                    app.T.AmplitudWilson2_2 = zeros(0)
                    app.T.AmplitudWilson2_3 = zeros(0)
                end
                if app.MediaCuadraticaCheckBox.Value == 1
                    app.T.MediaCuadratica1_1 = zeros(0)
                    app.T.MediaCuadratica1_2 = zeros(0)
                    app.T.MediaCuadratica1_3 = zeros(0)
                    app.T.MediaCuadratica2_1 = zeros(0)
                    app.T.MediaCuadratica2_2 = zeros(0)
                    app.T.MediaCuadratica2_3 = zeros(0)
                end
            end


        end

        % Callback function
        function TomarMuestraButtonPushed(app, event)


        end

        % Button pushed function: GuardarArchivoButton
        function GuardarArchivoButtonPushed(app, event)
            
            NombreA = app.NombreDelArchivoEditField.Value;
            writetable(app.T,string(NombreA)+"."+'xls')
        end

        % Callback function
        function RegresarButtonPushed(app, event)
            
            %
            Interfaz_Inicio
            delete(app.Bit)
            clear all
        end

        % Value changed function: EmpezarStreamingSwitch
        function EmpezarStreamingSwitchValueChanged(app, event)
            value = app.EmpezarStreamingSwitch.Value;
            if strcmp( value,'On')
                app.flag_recibir2 =1;
                startBackground(app.Bit)
                app.Lamp_3.Enable="on";
                app.CalibrarButton.Enable = "on";
              
            else
                app.flag_recibir2 =0;
                stopBackground(app.Bit)
                app.CalibrarButton.Enable = "off";
                app.Lamp_3.Enable="off";
            end
            
            
        end

        % Value changed function: ConectarBitalinoSwitch
        function ConectarBitalinoSwitchValueChanged(app, event)
            value = app.ConectarBitalinoSwitch.Value;
            
            if strcmp( value,'On')
                app.NombreBit = app.NombreBluetoothBitalinoEditField.Value;
                app.Bit = Bitalino('btspp://201806130189'); %Conexión con BIT 09
                %app.Bit = bitalino('btspp://201806132136'); %Conexión con BIT 04
                app.flag_recibir1=1;
                app.Lamp_2.Enable="on";
            else
                app.Lamp_2.Enable="off";
                app.flag_recibir1=0;
                
                
                clear all


            end
        end

        % Button pushed function: IniciarsesinButton
        function IniciarsesinButtonPushed(app, event)
            %Extracción características
            app.ctrl2=0;
            app.time = 0;
            app.flag_terminar=0;
            app.NMuestras=0;
            app.BanderaParaEmpezar=0;
            
            app.n=0;
            
            Tiempo_DMuestra = app.TiempodemuestraatomarEditField.Value;


            if app.SensorCheckBox.Value == 1
                while (app.flag_recibir1==1)&& (app.flag_terminar==0)&& (app.flag_recibir2 ==1)
                    data = read(app.Bit);% tomamos los ultimos valores en el buffer.\
                    pause(0.5);
                    
                    
                    EMG_raw = data(:,6); %se toma solo la columna de la senal EMG
                    EMG = EMG_raw*(3.3/1024);%se convierte la senal a la escala de 3.3 V
                    data_f = filter(app.F_pb, EMG');                          %Aplicar filtro pasa bandas
                    app.data_n = filter(app.F_notch, data_f);                       %Aplicar filtro notch
                    if size(app.data_n,2) >= 500 && size(app.data_n,2) <= 600
                        %EMG_ventana = [EMG_ventana;l];
                        app.EMGp = app.data_n(1, 60:end);
                        app.presente = size(app.EMGp,2);
                        app.l = app.presente + app.pasado;
                        
                        if  app.l > length(app.buffer)
                            app.l = 1;
                            app.buffer(1, 1:size(app.EMGp,2)) = app.EMGp; 
                            MAV = jMeanAbsoluteDeviation(app.EMGp);
                            % if MAV > 0.1500
                            %     app.menor = app.pasado - 300;
                            %     app.menor = 2000-app.menor;
                            %     app.EMG_ventana = [app.buffer(1, app.menor:2000), app.buffer(1, size(app.EMGp,2) + 300)];
                            %     app.BanderaParaEmpezar= 1;
                            % end
                
                        else
                            app.buffer(1, app.pasado:app.l-1) = app.EMGp; 
                            MAV = jMeanAbsoluteDeviation(app.EMGp);
                            if MAV > app.Promedio
                                if app.pasado - 300 < 0
                                    app.menor = app.pasado - 300;
                                    app.menor = 3000-app.menor;
                                    app.EMG_ventana = [app.buffer(1, app.menor:3000), app.buffer(1, 1:size(app.EMGp,2) + 300)];
                                    app.EMG_ventana = detecta_EMG(app.EMG_ventana,1000,500,100,1.5,0);
                                    app.BanderaParaEmpezar= 1;
                                elseif app.l + 300 > 3000
                                    app.mayor = app.l + 300;
                                    app.mayor = app.mayor-3000;
                                    app.EMG_ventana = [app.buffer(1, (size(app.EMGp,2) + 300):3000),app.buffer(1, 1:app.mayor)];
                                    app.EMG_ventana = detecta_EMG(app.EMG_ventana,1000,500,100,1.5,0);
                                    app.BanderaParaEmpezar= 1;
                                else
                                    app.EMG_ventana =  app.buffer(1, app.pasado - 300: app.l + 300);
                                    app.EMG_ventana = detecta_EMG(app.EMG_ventana,1000,500,100,1.5,0);
                                    app.BanderaParaEmpezar= 1;
                                    
                                end
                            end
                        end
                    end
                    app.pasado = app.l;
                    
                    
                    y = app.EMG_ventana';
                    ylim(app.figFpz, [-3.3 3.3]);
                    Epocas = size(y,2) ; %se toma el tamano del buffer obtenido
                    L = Epocas; %longitud del vector epocas
                    
                    t=linspace(0, Epocas/1000, L);
                    h1 = plot(app.figFpz, t, y, 'Color','r'); %se plotea esa muestra
                    
           
                    if (app.Pausa == 0)&&(app.BanderaParaEmpezar == 1)
                        app.NMuestras = app.NMuestras +1;
                        app.Label_9.Text = string(app.NMuestras);
                        app.n = app.n+1;
                        app.ctrl2 = app.ctrl2 + 1;     
                        app.EMG_s1 = y(1,1:100);
                        app.EMG_s2 = y(1,101:200);
                        app.EMG_s3 = y(1,201:300);
                        
                        if app.ZeroCrossingCheckBox.Value == 1
                            app.T.ZC1_1(app.n) = jZeroCrossing(app.EMG_s1,0.1);
                            app.T.ZC1_2(app.n) = jZeroCrossing(app.EMG_s2,0.1);
                            app.T.ZC1_3(app.n) = jZeroCrossing(app.EMG_s3,0.1);
                            
                        end
                        if app.MAVCheckBox.Value == 1
                            app.T.MAV1_1(app.n) = jMeanAbsoluteDeviation(app.EMG_s1);
                            app.T.MAV1_2(app.n) = jMeanAbsoluteDeviation(app.EMG_s2);
                            app.T.MAV1_3(app.n) = jMeanAbsoluteDeviation(app.EMG_s3);
                            
                        end
                        if app.KurtosisCheckBox.Value == 1
                            app.T.Kurtosis1_1(app.n) = jKurtosis(app.EMG_s1);
                            app.T.Kurtosis1_2(app.n) = jKurtosis(app.EMG_s2);
                            app.T.Kurtosis1_3(app.n) = jKurtosis(app.EMG_s3);
                            
                        end
                        if app.PromediodeEnergaCheckBox.Value == 1
                            app.T.AverageE1_1(app.n) = jAverageEnergy(app.EMG_s1);
                            app.T.AverageE1_2(app.n) = jAverageEnergy(app.EMG_s2);
                            app.T.AverageE1_3(app.n) = jAverageEnergy(app.EMG_s3);
                            
                        end
                        
                        if app.VarianzaCheckBox.Value == 1
                            app.T.VarianzaE1_1(app.n) = jVariance(app.EMG_s1);
                            app.T.VarianzaE1_2(app.n) = jVariance(app.EMG_s2);
                            app.T.VarianzaE1_3(app.n) = jVariance(app.EMG_s3);
                            
                        end
                        if app.AmplituddeWilsonCheckBox.Value == 1
                            app.T.AmplitudWilson1_1(app.n) = jWillisonAmplitude(app.EMG_s1,0.1);
                            app.T.AmplitudWilson1_2(app.n) = jWillisonAmplitude(app.EMG_s2,0.1);
                            app.T.AmplitudWilson1_3(app.n) = jWillisonAmplitude(app.EMG_s3,0.1);
                            
                        end
                        if app.MediaCuadraticaCheckBox.Value == 1
                            app.T.MediaCuadratica1_1(app.n) = jRootMeanSquare(app.EMG_s1);
                            app.T.MediaCuadratica1_2(app.n) = jRootMeanSquare(app.EMG_s2);
                            app.T.MediaCuadratica1_3(app.n) = jRootMeanSquare(app.EMG_s3);
                            
                        end
                        app.LarecoleccinestenPausaLabel.Visible = 'off';
                        app.BanderaParaEmpezar = 0;
                        
                    end
                    if app.Pausa == 1
                        app.LarecoleccinestenPausaLabel.Visible = 'on';
                    end         
                end
            end
            if app.SensoresCheckBox.Value == 1
                while (app.flag_recibir2 ==1) && (app.flag_recibir1==1)&&(app.flag_recibir21 ==1) && (app.flag_recibir11==1)&& (app.flag_terminar==0)
                    data = read(app.Bit);% tomamos los ultimos valores en el buffer.\
                    data1 = read(app.Bit1);% tomamos los ultimos valores en el buffer.\
                    pause(0.5);
                    EMG_raw = data(:,6); %se toma solo la columna de la senal EMG
                    EMG = EMG_raw*(3.3/1024);%se convierte la senal a la escala de 3.3 V
                    data_f = filter(app.F_pb, EMG');                          %Aplicar filtro pasa bandas
                    app.data_n = filter(app.F_notch, data_f);                       %Aplicar filtro notch

                    EMG_raw1 = data1(:,6); %se toma solo la columna de la senal EMG
                    EMG1 = EMG_raw1*(3.3/1024);%se convierte la senal a la escala de 3.3 V
                    data_f1 = filter(app.F_pb, EMG1');                          %Aplicar filtro pasa bandas
                    app.data_n1 = filter(app.F_notch, data_f1);   

                    if size(app.data_n,2) >= 500 && size(app.data_n,2) <= 600
                        app.EMGp = app.data_n(1, 60:end);
                        app.presente = size(app.EMGp,2);
                        app.l = app.presente + app.pasado;

                        app.EMGp1 = app.data_n1(1, 60:end);
                        app.presente1 = size(app.EMGp1,2);
                        app.l1 = app.presente1 + app.pasado1;
                        
                        if  app.l > length(app.buffer)
                            app.l = 1;
                            app.buffer(1, 1:size(app.EMGp,2)) = app.EMGp; 
                            MAV = jMeanAbsoluteDeviation(app.EMGp);

                            app.l1 = 1;
                            app.buffer1(1, 1:size(app.EMGp1,2)) = app.EMGp1; 
                            MAV1 = jMeanAbsoluteDeviation(app.EMGp1);

                            MAVs = [MAV, MAV1];

                
                        else
                            app.buffer(1, app.pasado:app.l-1) = app.EMGp; 
                            app.buffer1(1, app.pasado1:app.l1-1) = app.EMGp1; 
                            MAV = jMeanAbsoluteDeviation(app.EMGp);
                            MAV1 = jMeanAbsoluteDeviation(app.EMGp1);

                            MAVs = [MAV, MAV1];
                            MAV = max(MAVs);
                            
                            if MAV > app.Promedio
                                if app.pasado - 300 < 0
                                    app.menor = app.pasado - 300;
                                    app.menor = 3000-app.menor;
                                    app.EMG_ventana = [app.buffer(1, app.menor:3000), app.buffer(1, 1:size(app.EMGp,2) + 300)];
                                    app.EMG_ventana = detecta_EMG(app.EMG_ventana,1000,500,100,1.5,0);

                                    app.EMG_ventana1 = [app.buffer1(1, app.menor:3000), app.buffer1(1, 1:size(app.EMGp1,2) + 300)];
                                    app.EMG_ventana1 = detecta_EMG(app.EMG_ventana1,1000,500,100,1.5,0);

                                    app.BanderaParaEmpezar= 1;
                                elseif app.l + 300 > 3000
                                    app.mayor = app.l + 300;
                                    app.mayor = app.mayor-3000;
                                    app.EMG_ventana = [app.buffer(1, (size(app.EMGp,2) + 300):3000),app.buffer(1, 1:app.mayor)];
                                    app.EMG_ventana = detecta_EMG(app.EMG_ventana,1000,500,100,1.5,0);

                                    app.EMG_ventana1 = [app.buffer1(1, (size(app.EMGp1,2) + 300):3000),app.buffer1(1, 1:app.mayor)];
                                    app.EMG_ventana1 = detecta_EMG(app.EMG_ventana1,1000,500,100,1.5,0);

                                    app.BanderaParaEmpezar= 1;
                                else
                                    app.EMG_ventana =  app.buffer(1, app.pasado - 300: app.l + 300);
                                    app.EMG_ventana = detecta_EMG(app.EMG_ventana,1000,500,100,1.5,0);

                                    app.EMG_ventana1 =  app.buffer1(1, app.pasado1 - 300: app.l1 + 300);
                                    app.EMG_ventana1 = detecta_EMG(app.EMG_ventana1,1000,500,100,1.5,0);

                                    app.BanderaParaEmpezar= 1;
                                    
                                end
                            end
                            if  MAV1 > app.Promedio2
                                if app.pasado - 300 < 0
                                    app.menor = app.pasado - 300;
                                    app.menor = 3000-app.menor;
                                    app.EMG_ventana = [app.buffer(1, app.menor:3000), app.buffer(1, 1:size(app.EMGp,2) + 300)];
                                    app.EMG_ventana = detecta_EMG(app.EMG_ventana,1000,500,100,1.5,0);

                                    app.EMG_ventana1 = [app.buffer1(1, app.menor:3000), app.buffer1(1, 1:size(app.EMGp1,2) + 300)];
                                    app.EMG_ventana1 = detecta_EMG(app.EMG_ventana1,1000,500,100,1.5,0);

                                    app.BanderaParaEmpezar= 1;
                                elseif app.l + 300 > 3000
                                    app.mayor = app.l + 300;
                                    app.mayor = app.mayor-3000;
                                    app.EMG_ventana = [app.buffer(1, (size(app.EMGp,2) + 300):3000),app.buffer(1, 1:app.mayor)];
                                    app.EMG_ventana = detecta_EMG(app.EMG_ventana,1000,500,100,1.5,0);

                                    app.EMG_ventana1 = [app.buffer1(1, (size(app.EMGp1,2) + 300):3000),app.buffer1(1, 1:app.mayor)];
                                    app.EMG_ventana1 = detecta_EMG(app.EMG_ventana1,1000,500,100,1.5,0);

                                    app.BanderaParaEmpezar= 1;
                                else
                                    app.EMG_ventana =  app.buffer(1, app.pasado - 300: app.l + 300);
                                    app.EMG_ventana = detecta_EMG(app.EMG_ventana,1000,500,100,1.5,0);

                                    app.EMG_ventana1 =  app.buffer1(1, app.pasado1 - 300: app.l1 + 300);
                                    app.EMG_ventana1 = detecta_EMG(app.EMG_ventana1,1000,500,100,1.5,0);

                                    app.BanderaParaEmpezar= 1;
                                    
                                end
                            end
                        
                        end
                    end
                    app.pasado = app.l;
                    app.pasado1 = app.l1;
                    
                    
                    y = app.EMG_ventana';
                    ylim(app.figFpz, [-3.3 3.3]);
                    Epocas = size(y,2) ; %se toma el tamano del buffer obtenido
                    L = Epocas; %longitud del vector epocas
                    
                    t=linspace(0, Epocas/1000, L);

                    y1 = app.EMG_ventana1';
                    ylim(app.figFpz_2, [-3.3 3.3]);
                    Epocas1 = size(y1,2) ; %se toma el tamano del buffer obtenido
                    L1 = Epocas1; %longitud del vector epocas
                    
                    t1=linspace(0, Epocas1/1000, L1);
                    h1 = plot(app.figFpz, t, y, 'Color','r'); %se plotea esa muestra
                    h2 = plot(app.figFpz_2, t1, y1, 'Color','b'); %se plotea esa muestra

                    if (app.Pausa == 0)&&(app.BanderaParaEmpezar == 1)
                        app.NMuestras = app.NMuestras +1;
                        app.Label_9.Text = string(app.NMuestras);
                        app.n = app.n+1;
                        app.ctrl2 = app.ctrl2 + 1;     
                        app.EMG_s1 = y(1,1:167);
                        app.EMG_s2 = y(1,168:334);
                        app.EMG_s3 = y(1,335:500);

                        app.EMG_s1_2 = y1(1,1:167);
                        app.EMG_s2_2 = y1(1,168:334);
                        app.EMG_s3_2 = y1(1,335:500);
                        
                        if app.ZeroCrossingCheckBox.Value == 1
                            app.T.ZC1_1(app.n) = jZeroCrossing(app.EMG_s1,0.1);
                            app.T.ZC1_2(app.n) = jZeroCrossing(app.EMG_s2,0.1);
                            app.T.ZC1_3(app.n) = jZeroCrossing(app.EMG_s3,0.1);
                            app.T.ZC2_1(app.n) = jZeroCrossing(app.EMG_s1_2,0.1);
                            app.T.ZC2_2(app.n) = jZeroCrossing(app.EMG_s2_2,0.1);
                            app.T.ZC2_3(app.n) = jZeroCrossing(app.EMG_s3_2,0.1);
                            
                        end
                        if app.MAVCheckBox.Value == 1
                            app.T.MAV1_1(app.n) = jMeanAbsoluteDeviation(app.EMG_s1);
                            app.T.MAV1_2(app.n) = jMeanAbsoluteDeviation(app.EMG_s2);
                            app.T.MAV1_3(app.n) = jMeanAbsoluteDeviation(app.EMG_s3);
                            app.T.MAV2_1(app.n) = jMeanAbsoluteDeviation(app.EMG_s1_2);
                            app.T.MAV2_2(app.n) = jMeanAbsoluteDeviation(app.EMG_s2_2);
                            app.T.MAV2_3(app.n) = jMeanAbsoluteDeviation(app.EMG_s3_2);
              
                            
                        end
                        if app.KurtosisCheckBox.Value == 1
                            app.T.Kurtosis1_1(app.n) = jKurtosis(app.EMG_s1);
                            app.T.Kurtosis1_2(app.n) = jKurtosis(app.EMG_s2);
                            app.T.Kurtosis1_3(app.n) = jKurtosis(app.EMG_s3);
                            app.T.Kurtosis2_1(app.n) = jKurtosis(app.EMG_s1_2);
                            app.T.Kurtosis2_2(app.n) = jKurtosis(app.EMG_s2_2);
                            app.T.Kurtosis2_3(app.n) = jKurtosis(app.EMG_s3_2);
                            
                        end
                        if app.PromediodeEnergaCheckBox.Value == 1
                            app.T.AverageE1_1(app.n) = jAverageEnergy(app.EMG_s1);
                            app.T.AverageE1_2(app.n) = jAverageEnergy(app.EMG_s2);
                            app.T.AverageE1_3(app.n) = jAverageEnergy(app.EMG_s3);
                            app.T.AverageE2_1(app.n) = jAverageEnergy(app.EMG_s1_2);
                            app.T.AverageE2_2(app.n) = jAverageEnergy(app.EMG_s2_2);
                            app.T.AverageE2_3(app.n) = jAverageEnergy(app.EMG_s3_2);
                            
                        end
                        
                        if app.VarianzaCheckBox.Value == 1
                            app.T.VarianzaE1_1(app.n) = jVariance(app.EMG_s1);
                            app.T.VarianzaE1_2(app.n) = jVariance(app.EMG_s2);
                            app.T.VarianzaE1_3(app.n) = jVariance(app.EMG_s3);
                            app.T.VarianzaE2_1(app.n) = jVariance(app.EMG_s1_2);
                            app.T.VarianzaE2_2(app.n) = jVariance(app.EMG_s2_2);
                            app.T.VarianzaE2_3(app.n) = jVariance(app.EMG_s3_2);
                            
                        end
                        if app.AmplituddeWilsonCheckBox.Value == 1
                            app.T.AmplitudWilson1_1(app.n) = jWillisonAmplitude(app.EMG_s1,0.1);
                            app.T.AmplitudWilson1_2(app.n) = jWillisonAmplitude(app.EMG_s2,0.1);
                            app.T.AmplitudWilson1_3(app.n) = jWillisonAmplitude(app.EMG_s3,0.1);
                            app.T.AmplitudWilson2_1(app.n) = jWillisonAmplitude(app.EMG_s1_2,0.1);
                            app.T.AmplitudWilson2_2(app.n) = jWillisonAmplitude(app.EMG_s2_2,0.1);
                            app.T.AmplitudWilson2_3(app.n) = jWillisonAmplitude(app.EMG_s3_2,0.1);
                            
                        end
                        if app.MediaCuadraticaCheckBox.Value == 1
                            app.T.MediaCuadratica1_1(app.n) = jRootMeanSquare(app.EMG_s1);
                            app.T.MediaCuadratica1_2(app.n) = jRootMeanSquare(app.EMG_s2);
                            app.T.MediaCuadratica1_3(app.n) = jRootMeanSquare(app.EMG_s3);
                            app.T.MediaCuadratica2_1(app.n) = jRootMeanSquare(app.EMG_s1_2);
                            app.T.MediaCuadratica2_2(app.n) = jRootMeanSquare(app.EMG_s2_2);
                            app.T.MediaCuadratica2_3(app.n) = jRootMeanSquare(app.EMG_s3_2);
                            
                        end
                        app.LarecoleccinestenPausaLabel.Visible = 'off';
                        app.BanderaParaEmpezar = 0;
                    end
                    if app.Pausa == 1
                        app.LarecoleccinestenPausaLabel.Visible = 'on';
                    end 
                end
            end
            
        end

        % Callback function
        function ClaseagrabarDropDownValueChanged(app, event)
            value = app.ClaseagrabarDropDown.Value;
            switch value
                case 'Wrist-Up'
                    app.Image.ImageSource = imread("WristUp.jpg");
                case 'Wrist-Down'
                    app.Image.ImageSource = imread("manita.jfif");
                    
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            app.Pausa = 1;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            
            app.Pausa =0;
        end

        % Callback function
        function Button_3Pushed(app, event)
            app.LarecoleccinestenPausaLabel.Visible = 'on';
        end

        % Value changed function: SeleccionartodoCheckBox
        function SeleccionartodoCheckBoxValueChanged(app, event)
            value = app.SeleccionartodoCheckBox.Value;
            
            if app.SeleccionartodoCheckBox.Value ==1
                
                app.ZeroCrossingCheckBox.Value=1;
                app.MAVCheckBox.Value=1;
                app.KurtosisCheckBox.Value=1;
                app.PromediodeEnergaCheckBox.Value=1;
                app.VarianzaCheckBox.Value=1;
                app.AmplituddeWilsonCheckBox.Value=1;
                app.MediaCuadraticaCheckBox.Value=1;
            end
            if app.SeleccionartodoCheckBox.Value ==0
                
                app.ZeroCrossingCheckBox.Value=0;
                app.MAVCheckBox.Value=0;
                app.KurtosisCheckBox.Value=0;
                app.PromediodeEnergaCheckBox.Value=0;
                app.VarianzaCheckBox.Value=0;
                app.AmplituddeWilsonCheckBox.Value=0;
                app.MediaCuadraticaCheckBox.Value=0;
            end
        end

        % Button pushed function: TerminarsesinButton
        function TerminarsesinButtonPushed(app, event)
            app.flag_terminar=1;
            app.n=0;
            app.ctrl2=0;
            app.time = 0;
            app.NMuestras=0;
            app.BanderaParaEmpezar=0;
            app.T = table();
            a.data_n=0;
            a.data_n1=0;
        
            if app.Pausa == 1
                app.LarecoleccinestenPausaLabel.Visible = 'off';
                app.Pausa=0;
            end
            
        end

        % Value changed function: SensorCheckBox
        function SensorCheckBoxValueChanged(app, event)
            value = app.SensorCheckBox.Value;
            if value == 1
                app.EmpezarStreamingSwitch_2.Enable = "off";
                app.ConectarBitalinoSwitch_2.Enable = "off";
                app.SensoresCheckBox.Value = 0;
            end
        end

        % Value changed function: SensoresCheckBox
        function SensoresCheckBoxValueChanged(app, event)
            value = app.SensoresCheckBox.Value;
            if value == 1
                app.EmpezarStreamingSwitch_2.Enable = "on";
                app.ConectarBitalinoSwitch_2.Enable = "on";
                app.SensorCheckBox.Value = 0;
            end
        end

        % Value changed function: ConectarBitalinoSwitch_2
        function ConectarBitalinoSwitch_2ValueChanged(app, event)
            value = app.ConectarBitalinoSwitch_2.Value;
            if strcmp( value,'On')
                app.NombreBit1 = app.NombreBluetoothBitalino2EditField.Value;
                app.Bit1 = Bitalino('btspp://201806132136'); %Conexión con BITalino 04
                %app.Bit1 = Bitalino('btspp://201806130189'); %Conexión con BItalino 09
                app.flag_recibir11=1;
                app.Lamp_4.Enable="on";
            else
                app.Lamp_4.Enable="off";
                app.flag_recibir11=0;
               
                
            end
        end

        % Value changed function: EmpezarStreamingSwitch_2
        function EmpezarStreamingSwitch_2ValueChanged(app, event)
            value = app.EmpezarStreamingSwitch_2.Value;
            if strcmp( value,'On')
                
                app.flag_recibir21 =1;
                startBackground(app.Bit1)
                app.Lamp_5.Enable="on";
         
            else
                app.flag_recibir21 =0;
                stopBackground(app.Bit1)
                app.Lamp_5.Enable="off";
            end
        end

        % Callback function
        function EmpezarStreamingSwitchValueChanged2(app, event)
            value = app.EmpezarStreamingSwitch.Value;
            
        end

        % Button pushed function: CalibrarButton
        function CalibrarButtonPushed(app, event)
            MAVs = [];
            MAVs1 = [];
            if app.SensorCheckBox.Value == 1
                for i = 1:20
                    data = read(app.Bit);
                    pause(0.5);
                    EMG_raw = data(:,6);
                    EMG = EMG_raw*(3.3/1024);
                
                    data_fP = filter(app.F_pb, EMG');                          %Aplicar filtro pasa bandas
                    data_nP = filter(app.F_notch, data_fP);
                
                    if size(data_nP,2) >= 500 && size(data_nP,2) <= 600
                        EMGpP = data_nP(1, 60:end);
                        MAV = jMeanAbsoluteDeviation(EMGpP);
                        MAVs = [MAVs, MAV];
                
                    end
                    
                end
                app.Promedio = max(MAVs) + 0.015;
                app.CalibradoLamp.Enable = "on";
            end

            if app.SensoresCheckBox.Value == 1
                for i = 1:20
                    data = read(app.Bit);
                    data1 = read(app.Bit1);
                    pause(0.5);
                    EMG_raw = data(:,6);
                    EMG = EMG_raw*(3.3/1024);
                    EMG_raw1 = data1(:,6);
                    EMG1 = EMG_raw1*(3.3/1024);
                
                    data_fP = filter(app.F_pb, EMG');                          %Aplicar filtro pasa bandas
                    data_nP = filter(app.F_notch, data_fP);

                    data_fP1 = filter(app.F_pb, EMG1');                          %Aplicar filtro pasa bandas
                    data_nP1 = filter(app.F_notch, data_fP1);
                
                    if size(data_nP,2) >= 500 && size(data_nP,2) <= 600
                        EMGpP = data_nP(1, 60:end);
                        MAV = jMeanAbsoluteDeviation(EMGpP);
                        MAVs = [MAVs, MAV];

                        EMGpP1 = data_nP1(1, 60:end);
                        MAV1 = jMeanAbsoluteDeviation(EMGpP1);
                        MAVs1 = [MAVs1, MAV1];
                
                    end
                    
                end

                app.Promedio = max(MAVs) + 0.015;
                app.Promedio2 = max(MAVs1) + 0.015;
                app.CalibradoLamp.Enable = "on";
            
            end

        end

        % Callback function
        function SeleccionartodoCheckBoxValueChanged2(app, event)

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.figFpz = uiaxes(app.UIFigure);
            title(app.figFpz, 'Muestra Tomada')
            xlabel(app.figFpz, 't (s)')
            ylabel(app.figFpz, 'V')
            app.figFpz_2 = uiaxes(app.UIFigure);
            title(app.figFpz_2, 'Muestra Tomada')
            xlabel(app.figFpz_2, 't (s)')
            ylabel(app.figFpz_2, 'V')
            app.Panel = uipanel(app.UIFigure);
            app.RecoleccindeDatosEMGUVGLabel = uilabel(app.Panel);
            app.RecoleccindeDatosEMGUVGLabel.Text = 'Recolección de Datos  EMG - UVG';
            app.DesconectadoLampLabel = uilabel(app.Panel);
            app.DesconectadoLampLabel.Enable = 'off';
            app.DesconectadoLampLabel.Text = 'Desconectado';
            app.DesconectadoLamp = uilamp(app.Panel);
            app.DesconectadoLamp.Enable = 'off';
            app.CalibradoLampLabel = uilabel(app.Panel);
            app.CalibradoLampLabel.Enable = 'off';
            app.CalibradoLampLabel.Text = 'Calibrado';
            app.CalibradoLamp = uilamp(app.Panel);
            app.CalibradoLamp.Enable = 'off';
            app.LarecoleccinestenPausaLabel = uilabel(app.UIFigure);
            app.LarecoleccinestenPausaLabel.Visible = 'off';
            app.LarecoleccinestenPausaLabel.Text = 'La recolección está en Pausa';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InstruccionesTab = uitab(app.TabGroup);
            app.InstruccionesTab.Title = 'Instrucciones';
            app.Label = uilabel(app.InstruccionesTab);
            app.Label.Text = '1. Se tienen que seleccionar las caracteristicas que se quieren incluir en los vectores de caracteristicas. ';
            app.Label_2 = uilabel(app.InstruccionesTab);
            app.Label_2.Text = '2. Al tener las características seleccionadas se debe crear la tabla donde se guardaran estos vectores. ';
            app.InstruccionesparaelusodelaInterfazLabel = uilabel(app.InstruccionesTab);
            app.InstruccionesparaelusodelaInterfazLabel.Text = 'Instrucciones para el uso de la Interfaz';
            app.Label_3 = uilabel(app.InstruccionesTab);
            app.Label_3.Text = '5. Se debe de conectar al Bitalino y luego empezar a recolectar datos empezando el streaming.  ';
            app.Label_4 = uilabel(app.InstruccionesTab);
            app.Label_4.Text = '3. Se debe de seleccionar la ventana de tiempo de las muestras que se tomarán.';
            app.Label_5 = uilabel(app.InstruccionesTab);
            app.Label_5.Text = '6. Para parar de recolectar data, se debe de apagar el streaming. ';
            app.Label_6 = uilabel(app.InstruccionesTab);
            app.Label_6.Text = '4. Se debe de seleccionar que clase se grabará. ';
            app.Label_7 = uilabel(app.InstruccionesTab);
            app.Label_7.Text = '7. Guardar la tabla, se recomienda colocar el nombre que identifique la clase y datos sobre el ambiente. ';
            app.PaneldecontrolTab = uitab(app.TabGroup);
            app.PaneldecontrolTab.Title = 'Panel de control ';
            app.GrabacinPanel = uipanel(app.PaneldecontrolTab);
            app.GrabacinPanel.Title = 'Grabación ';
            app.GuardarArchivoButton = uibutton(app.GrabacinPanel, 'push');
            app.GuardarArchivoButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarArchivoButtonPushed, true);
            app.GuardarArchivoButton.Text = 'Guardar Archivo';
            app.TiempodemuestraatomarEditFieldLabel = uilabel(app.GrabacinPanel);
            app.TiempodemuestraatomarEditFieldLabel.Text = 'Tiempo de muestra a tomar';
            app.TiempodemuestraatomarEditField = uieditfield(app.GrabacinPanel, 'numeric');
            app.TiempodemuestraatomarEditField.Limits = [1 20];
            app.TiempodemuestraatomarEditField.RoundFractionalValues = 'on';
            app.TiempodemuestraatomarEditField.Value = 1;
            app.NombreDelArchivoEditFieldLabel = uilabel(app.GrabacinPanel);
            app.NombreDelArchivoEditFieldLabel.Text = 'Nombre Del Archivo';
            app.NombreDelArchivoEditField = uieditfield(app.GrabacinPanel, 'text');
            app.NombreDelArchivoEditField.Value = 'Sesion_n_';
            app.IniciarsesinButton = uibutton(app.GrabacinPanel, 'push');
            app.IniciarsesinButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarsesinButtonPushed, true);
            app.IniciarsesinButton.Text = 'Iniciar sesión';
            app.NmerodemuestrastomadasLabel = uilabel(app.GrabacinPanel);
            app.NmerodemuestrastomadasLabel.Text = 'Número de muestras tomadas:';
            app.Label_9 = uilabel(app.GrabacinPanel);
            app.Label_9.Text = '0';
            app.Button = uibutton(app.GrabacinPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '';
            app.Button_2 = uibutton(app.GrabacinPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '';
            app.TerminarsesinButton = uibutton(app.GrabacinPanel, 'push');
            app.TerminarsesinButton.ButtonPushedFcn = createCallbackFcn(app, @TerminarsesinButtonPushed, true);
            app.TerminarsesinButton.Text = 'Terminar sesión';
            app.Lamp_4 = uilamp(app.GrabacinPanel);
            app.Lamp_5 = uilamp(app.GrabacinPanel);
            app.ConectarBitalinoSwitch_2Label = uilabel(app.GrabacinPanel);
            app.ConectarBitalinoSwitch_2Label.Enable = 'off';
            app.ConectarBitalinoSwitch_2Label.Text = 'Conectar Bitalino';
            app.ConectarBitalinoSwitch_2 = uiswitch(app.GrabacinPanel, 'slider');
            app.ConectarBitalinoSwitch_2.ValueChangedFcn = createCallbackFcn(app, @ConectarBitalinoSwitch_2ValueChanged, true);
            app.ConectarBitalinoSwitch_2.Enable = 'off';
            app.EmpezarStreamingSwitch_2Label = uilabel(app.GrabacinPanel);
            app.EmpezarStreamingSwitch_2Label.Enable = 'off';
            app.EmpezarStreamingSwitch_2Label.Text = 'Empezar Streaming';
            app.EmpezarStreamingSwitch_2 = uiswitch(app.GrabacinPanel, 'slider');
            app.EmpezarStreamingSwitch_2.ValueChangedFcn = createCallbackFcn(app, @EmpezarStreamingSwitch_2ValueChanged, true);
            app.EmpezarStreamingSwitch_2.Enable = 'off';
            app.NombreBluetoothBitalino2EditFieldLabel = uilabel(app.GrabacinPanel);
            app.NombreBluetoothBitalino2EditFieldLabel.Text = 'Nombre Bluetooth Bitalino 2';
            app.NombreBluetoothBitalino2EditField = uieditfield(app.GrabacinPanel, 'text');
            app.NombreBluetoothBitalino2EditField.Value = 'BITalino';
            app.CaracteristicasSeleccionadasPanel = uipanel(app.PaneldecontrolTab);
            app.CaracteristicasSeleccionadasPanel.Title = 'Caracteristicas Seleccionadas ';
            app.ZeroCrossingCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.ZeroCrossingCheckBox.Text = 'Zero Crossing';
            app.MAVCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.MAVCheckBox.Text = 'MAV';
            app.KurtosisCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.KurtosisCheckBox.Text = 'Kurtosis';
            app.PromediodeEnergaCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.PromediodeEnergaCheckBox.Text = 'Promedio de Energía';
            app.VarianzaCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.VarianzaCheckBox.Text = 'Varianza';
            app.AmplituddeWilsonCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.AmplituddeWilsonCheckBox.Text = 'Amplitud de Wilson';
            app.MediaCuadraticaCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.MediaCuadraticaCheckBox.Text = 'Media Cuadratica ';
            app.CrearTablaButton = uibutton(app.CaracteristicasSeleccionadasPanel, 'push');
            app.CrearTablaButton.ButtonPushedFcn = createCallbackFcn(app, @CrearTablaButtonPushed, true);
            app.CrearTablaButton.Text = 'Crear Tabla';
            app.ConectarBitalinoSwitchLabel = uilabel(app.CaracteristicasSeleccionadasPanel);
            app.ConectarBitalinoSwitchLabel.Text = 'Conectar Bitalino';
            app.ConectarBitalinoSwitch = uiswitch(app.CaracteristicasSeleccionadasPanel, 'slider');
            app.ConectarBitalinoSwitch.ValueChangedFcn = createCallbackFcn(app, @ConectarBitalinoSwitchValueChanged, true);
            app.EmpezarStreamingSwitchLabel = uilabel(app.CaracteristicasSeleccionadasPanel);
            app.EmpezarStreamingSwitchLabel.Text = 'Empezar Streaming';
            app.EmpezarStreamingSwitch = uiswitch(app.CaracteristicasSeleccionadasPanel, 'slider');
            app.EmpezarStreamingSwitch.ValueChangedFcn = createCallbackFcn(app, @EmpezarStreamingSwitchValueChanged, true);
            app.NombreBluetoothBitalinoEditFieldLabel = uilabel(app.CaracteristicasSeleccionadasPanel);
            app.NombreBluetoothBitalinoEditFieldLabel.Text = 'Nombre Bluetooth Bitalino';
            app.NombreBluetoothBitalinoEditField = uieditfield(app.CaracteristicasSeleccionadasPanel, 'text');
            app.NombreBluetoothBitalinoEditField.Value = 'BITalino';
            app.Lamp_2 = uilamp(app.CaracteristicasSeleccionadasPanel);
            app.Lamp_3 = uilamp(app.CaracteristicasSeleccionadasPanel);
            app.SeleccionartodoCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.SeleccionartodoCheckBox.ValueChangedFcn = createCallbackFcn(app, @SeleccionartodoCheckBoxValueChanged, true);
            app.SeleccionartodoCheckBox.Text = 'Seleccionar todo';
            app.SensorCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.SensorCheckBox.ValueChangedFcn = createCallbackFcn(app, @SensorCheckBoxValueChanged, true);
            app.SensorCheckBox.Text = '1 Sensor';
            app.SensorCheckBox.Value = true;
            app.SensoresCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.SensoresCheckBox.ValueChangedFcn = createCallbackFcn(app, @SensoresCheckBoxValueChanged, true);
            app.SensoresCheckBox.Text = '2 Sensores';
            app.CalibrarButton = uibutton(app.CaracteristicasSeleccionadasPanel, 'push');
            app.CalibrarButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrarButtonPushed, true);
            app.CalibrarButton.Enable = 'off';
            app.CalibrarButton.Text = 'Calibrar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RecoleccionDeDatosEMGv2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end